classdef mainDLapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeepLearningAppUIFigure        matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool2                      matlab.ui.container.toolbar.PushTool
        TabGroup                       matlab.ui.container.TabGroup
        ProblemtosolveTab              matlab.ui.container.Tab
        MechanismsofartificialintelligencealgorithmsLabel_2  matlab.ui.control.Label
        LoaddataButton                 matlab.ui.control.Button
        NetworkTab                     matlab.ui.container.Tab
        ApplyButton                    matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        ClassificationTab              matlab.ui.container.Tab
        SelectmodelButtonGroup         matlab.ui.container.ButtonGroup
        NumberofclassesEditField_2     matlab.ui.control.NumericEditField
        NumberofclassesEditField_2Label  matlab.ui.control.Label
        ImagesizeEditField_x           matlab.ui.control.NumericEditField
        InputimagesizeLabel            matlab.ui.control.Label
        ImagesizeEditFieldLabel_7      matlab.ui.control.Label
        ImagesizeEditFieldLabel_6      matlab.ui.control.Label
        ImagesizeEditFieldLabel_5      matlab.ui.control.Label
        ImagesizeEditField_z           matlab.ui.control.NumericEditField
        ImagesizeEditField_y           matlab.ui.control.NumericEditField
        IncludeweightsCheckBox         matlab.ui.control.CheckBox
        ResNet50Button                 matlab.ui.control.ToggleButton
        MobileNetv2Button              matlab.ui.control.ToggleButton
        Incepctionv3Button             matlab.ui.control.ToggleButton
        AlexNetButton                  matlab.ui.control.ToggleButton
        SimpleclassificationnetworkButton  matlab.ui.control.ToggleButton
        SegmentationTab                matlab.ui.container.Tab
        SelectmodelButtonGroup_2       matlab.ui.container.ButtonGroup
        ImagesizeEditFieldLabel_8      matlab.ui.control.Label
        ImagesizeEditFieldLabel_4      matlab.ui.control.Label
        ImagesizeEditFieldLabel_3      matlab.ui.control.Label
        ImagesizeEditFieldLabel_2      matlab.ui.control.Label
        ImagesizeEditField_z_s         matlab.ui.control.NumericEditField
        ImagesizeEditField_y_s         matlab.ui.control.NumericEditField
        ImagesizeEditField_x_s         matlab.ui.control.NumericEditField
        ImagesizeEditFieldLabel        matlab.ui.control.Label
        BackboneDropDown               matlab.ui.control.DropDown
        BackboneDropDownLabel          matlab.ui.control.Label
        NumberofclassesEditField       matlab.ui.control.NumericEditField
        NumberofclassesEditFieldLabel  matlab.ui.control.Label
        IncludeweightsCheckBox_2       matlab.ui.control.CheckBox
        UNetButton                     matlab.ui.control.ToggleButton
        DeepLabv3Button                matlab.ui.control.ToggleButton
        SimplesegmentationnetworkButton  matlab.ui.control.ToggleButton
        UIAxesModel                    matlab.ui.control.UIAxes
        DataTab                        matlab.ui.container.Tab
        ApplypartitioningButton        matlab.ui.control.Button
        TrainValidationTestdatarandompartitioningLabel  matlab.ui.control.Label
        DatasetsstatisticsLabel        matlab.ui.control.Label
        TabGroup3                      matlab.ui.container.TabGroup
        TrainTab_2                     matlab.ui.container.Tab
        TransparencySlider             matlab.ui.control.Slider
        TransparencySliderLabel        matlab.ui.control.Label
        ClassLabel                     matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ValidationTab                  matlab.ui.container.Tab
        TransparencySlider_2           matlab.ui.control.Slider
        TransparencySlider_2Label      matlab.ui.control.Label
        ClassLabel_2                   matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Slider_2                       matlab.ui.control.Slider
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        TestTab_2                      matlab.ui.container.Tab
        TransparencySlider_3           matlab.ui.control.Slider
        TransparencySlider_3Label      matlab.ui.control.Label
        ClassLabel_3                   matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Slider_3                       matlab.ui.control.Slider
        UIAxes2_3                      matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        TestdatasetEditField           matlab.ui.control.NumericEditField
        TestdatasetLabel               matlab.ui.control.Label
        IncludevalidationCheckBox      matlab.ui.control.CheckBox
        ValidationdatasetEditField     matlab.ui.control.NumericEditField
        ValidationdatasetEditFieldLabel  matlab.ui.control.Label
        TraindatasetEditField          matlab.ui.control.NumericEditField
        TraindatasetEditFieldLabel     matlab.ui.control.Label
        DataaugmentationTab            matlab.ui.container.Tab
        PanelSegmentation              matlab.ui.container.Panel
        UIAxes4_15                     matlab.ui.control.UIAxes
        UIAxes4_14                     matlab.ui.control.UIAxes
        UIAxes4_13                     matlab.ui.control.UIAxes
        UIAxes4_12                     matlab.ui.control.UIAxes
        UIAxes4_11                     matlab.ui.control.UIAxes
        UIAxes4_10                     matlab.ui.control.UIAxes
        PreviewaugmentationresultsLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        PanelClassification            matlab.ui.container.Panel
        UIAxes4_7                      matlab.ui.control.UIAxes
        UIAxes4_6                      matlab.ui.control.UIAxes
        UIAxes4_3                      matlab.ui.control.UIAxes
        UIAxes4_4                      matlab.ui.control.UIAxes
        UIAxes4_5                      matlab.ui.control.UIAxes
        UIAxes4_2                      matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        TrainTab                       matlab.ui.container.Tab
        StoptrainingButton             matlab.ui.control.Button
        TimeelapsedLabel               matlab.ui.control.Label
        TrainingwaitingtostartLabel    matlab.ui.control.Label
        HyperparametersPanel           matlab.ui.container.Panel
        ModeltotrainDropDown           matlab.ui.control.DropDown
        ModeltotrainDropDownLabel      matlab.ui.control.Label
        LossfunctionDropDown           matlab.ui.control.DropDown
        LossfunctionDropDownLabel      matlab.ui.control.Label
        TrainButton                    matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        OptimizerDropDown              matlab.ui.control.DropDown
        OptimizerDropDownLabel         matlab.ui.control.Label
        UIAxesLoss                     matlab.ui.control.UIAxes
        UIAxesAccuracy                 matlab.ui.control.UIAxes
        TestTab                        matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        GradCAMclassDropDown           matlab.ui.control.DropDown
        GradCAMclassDropDownLabel      matlab.ui.control.Label
        ShowgroundtruthmaskCheckBox    matlab.ui.control.CheckBox
        TransparencySliderSegmOv       matlab.ui.control.Slider
        TransparencySlider_5Label_2    matlab.ui.control.Label
        SegmentationqualitymetricsPanel  matlab.ui.container.Panel
        MeanBoundaryF1scoreLabel       matlab.ui.control.Label
        WeightedIoULabel               matlab.ui.control.Label
        MeanIoULabel                   matlab.ui.control.Label
        MeanaccuracyLabel              matlab.ui.control.Label
        GlobalaccuracyLabel            matlab.ui.control.Label
        MetricsDropDown_2              matlab.ui.control.DropDown
        MetricsDropDown_2Label         matlab.ui.control.Label
        ConfusionmatrixLabel           matlab.ui.control.Label
        TransparencySliderGradCAN      matlab.ui.control.Slider
        TransparencySlider_5Label      matlab.ui.control.Label
        Slider_4                       matlab.ui.control.Slider
        ApplyGradCAMButton             matlab.ui.control.Button
        PredictedclassLabel            matlab.ui.control.Label
        GroundtruthclassLabel          matlab.ui.control.Label
        SelectsavedmodelDropDownLabel  matlab.ui.control.Label
        TestmetricsPanel               matlab.ui.container.Panel
        MetricsDropDown                matlab.ui.control.DropDown
        MetricsDropDownLabel           matlab.ui.control.Label
        F1scoreLabel                   matlab.ui.control.Label
        RecallLabel                    matlab.ui.control.Label
        PrecisionLabel                 matlab.ui.control.Label
        AccuracyLabel                  matlab.ui.control.Label
        UIAxes4_9                      matlab.ui.control.UIAxes
        UIAxes4_8                      matlab.ui.control.UIAxes
        UIAxesConfMat                  matlab.ui.control.UIAxes
        NetworkinsidesTab              matlab.ui.container.Tab
        DrawrandomimageButton          matlab.ui.control.Button
        FeaturemapnumberSlider         matlab.ui.control.Slider
        FeaturemapnumberSliderLabel    matlab.ui.control.Label
        UsecolormapCheckBox            matlab.ui.control.CheckBox
        SelectlayerDropDownLabel       matlab.ui.control.Label
        SelectsavedmodelDropDown_2Label  matlab.ui.control.Label
        UIAxesII                       matlab.ui.control.UIAxes
        UIAxesAct                      matlab.ui.control.UIAxes
    end


    properties (Access = public)

    end

    methods (Access = public)
        function data = augmentImageAndLabel(data, xTrans, yTrans)
            % Augment images and pixel label images using random reflection and
            % translation.

            for i = 1:size(data,1)

                tform = randomAffine2d(...
                    'XReflection',true,...
                    'XTranslation', xTrans, ...
                    'YTranslation', yTrans);

                % Center the view at the center of image in the output space while
                % allowing translation to move the output image out of view.
                rout = affineOutputView(size(data{i,1}), tform, 'BoundsStyle', 'centerOutput');

                % Warp the image and pixel labels using the same transform.
                data{i,1} = imwarp(data{i,1}, tform, 'OutputView', rout);
                data{i,2} = imwarp(data{i,2}, tform, 'OutputView', rout);

            end
        end

        function dataOut = augmentData(data)
            disp('Inside augmentData');  % Debugging line
            disp(data{1});  % Print image data
            disp(data{2});  % Print label data
            % data_tab = app.UITable.Data;
            % augmenterSettings = struct(); % Initialize an empty struct
            % % Extract image and label
            % img = data{1};
            % label = data{2};
            %
            % for ix = 1:length(app.UITable.Data.Apply)
            %     if app.UITable.Data.Apply{ix} == 1
            %         data_f = app.UITable.Data.Value{ix};
            %         data_f = data_f(find(~isspace(data_f)));
            %         data_f = strsplit(data_f, ',');
            %         if length(data_f) > 1
            %             val_data = [str2num(data_f{1}) str2num(data_f{2})];
            %         else
            %             val_data = str2num(data_f{1});
            %         end
            %         augmenterSettings(1).(app.UITable.Data.Operation{ix}) = val_data;
            %     end
            % end
            % % Merge user settings with default values
            % fields = fieldnames(defaultValues);
            % for i = 1:numel(fields)
            %     if ~isfield(augmenterSettings, fields{i})
            %         augmenterSettings.(fields{i}) = defaultValues.(fields{i});
            %     end
            % end
            %
            % % ---- Sample values from the provided ranges ----
            % % Reflection (if enabled)
            % flipX = rand > 0.5 && augmenterSettings.RandXReflection;
            % flipY = rand > 0.5 && augmenterSettings.RandYReflection;
            %
            % % Rotation
            % angle = randi(augmenterSettings.RandRotation);
            %
            % % Scaling
            % scaleFactor = rand * (augmenterSettings.RandScale(2) - augmenterSettings.RandScale(1)) + augmenterSettings.RandScale(1);
            %
            % % Independent X/Y Scaling
            % scaleX = rand * (augmenterSettings.RandXScale(2) - augmenterSettings.RandXScale(1)) + augmenterSettings.RandXScale(1);
            % scaleY = rand * (augmenterSettings.RandYScale(2) - augmenterSettings.RandYScale(1)) + augmenterSettings.RandYScale(1);
            %
            % % Shear
            % shearX = rand * (augmenterSettings.RandXShear(2) - augmenterSettings.RandXShear(1)) + augmenterSettings.RandXShear(1);
            % shearY = rand * (augmenterSettings.RandYShear(2) - augmenterSettings.RandYShear(1)) + augmenterSettings.RandYShear(1);
            %
            % % Translation
            % tx = randi(augmenterSettings.RandXTranslation);
            % ty = randi(augmenterSettings.RandYTranslation);
            %
            % % ---- Apply Geometric Transformations ----
            % tform = affine2d([...
            %     scaleX, shearX, 0; ...
            %     shearY, scaleY, 0; ...
            %     tx, ty, 1]);
            %
            % % Apply transformation if needed
            % img = imwarp(img, tform, 'OutputView', imref2d(size(img)), 'FillValues', augmenterSettings.FillValue);
            % label = imwarp(label, tform, 'OutputView', imref2d(size(label)), 'FillValues', mode(label(:)), 'nearest');
            %
            % % ---- Apply Flipping ----
            % if flipX
            %     img = fliplr(img);
            %     label = fliplr(label);
            % end
            % if flipY
            %     img = flipud(img);
            %     label = flipud(label);
            % end
            %
            % % Convert label back to categorical
            % label = categorical(label);
            %
            % % Return augmented data
            % dataOut={img,label};

            if ~iscell(data) || numel(data) ~= 2
                error('Input data must be a cell array {image, label}');
            end

            img = data{1};   % Image data
            label = data{2}; % Label data

            % Apply your augmentations (example: random rotation)
            angle = randi([-20, 20]);  % Random rotation angle
            img = imrotate(img, angle, 'bilinear', 'crop');
            label = imrotate(label, angle, 'nearest', 'crop');

            % Return the augmented image and label as a cell array
            dataOut = {img, label};
        end

        % function stop = updatePlot(app, info)
        %     global alert
        %     global stopTrainingFlag;
        %     stop = false; % Allow training to continue
        %     global options
        %     % Create a persistent variable to store the loss values across iterations
        %     persistent lossHistory iterationHistory vallossHistory;
        %
        %     % Check if info contains training data (i.e., loss)
        %     if ~isempty(info.TrainingLoss)
        %         iteration = info.Iteration;
        %
        %         % Initialize the history variables if they don't exist
        %         if isempty(lossHistory)
        %             lossHistory = info.TrainingLoss;  % Store the current loss in history
        %             iterationHistory = iteration;     % Store the current iteration number
        %             if app.IncludevalidationCheckBox.Value == 1
        %                 vallossHistory = info.ValidationLoss;  % Store the current loss in history
        %             end
        %         else
        %             % Append the current loss and iteration to the history
        %             lossHistory = [lossHistory, info.TrainingLoss];
        %             iterationHistory = [iterationHistory, iteration];
        %             if app.IncludevalidationCheckBox.Value == 1
        %                 vallossHistory = [vallossHistory, info.ValidationLoss];  % Store the current loss in history
        %             end
        %         end
        %
        %         % Debugging: Display previous loss values
        %         disp('Previous Loss Values:');
        %         disp(lossHistory);
        %
        %         % Update Loss Plot using animatedline
        %         persistent lossLine valLossLine;
        %         if isempty(lossLine)
        %             lossLine = animatedline('Parent', app.UIAxesLoss, 'LineWidth', 1, 'Color', [0 0.4470 0.7410]);
        %             xlabel(app.UIAxesLoss, 'Iteration');
        %             ylabel(app.UIAxesLoss, 'Loss');
        %             if app.IncludevalidationCheckBox.Value == 1
        %                 valLossLine = animatedline('Parent', app.UIAxesLoss, 'LineWidth', 1, 'Color', [0.9290 0.6940 0.1250]);
        %             end
        %         end
        %         addpoints(lossLine, iteration, info.TrainingLoss(end));  % Add new point for loss
        %         if app.IncludevalidationCheckBox.Value == 1
        %             if ~isempty(info.ValidationLoss)
        %                 addpoints(valLossLine, iteration, info.ValidationLoss(end));  % Add new point for loss
        %             end
        %         end
        %         xlim(app.UIAxesLoss, [0 iteration]);  % Update the X axis limits
        %
        %         if app.IncludevalidationCheckBox.Value == 1
        %             ylim(app.UIAxesLoss, [0 max([lossHistory, vallossHistory])]);  % Adjust Y axis dynamically based on loss history
        %         else
        %             ylim(app.UIAxesLoss, [0 max(lossHistory)]);  % Adjust Y axis dynamically based on loss history
        %         end
        %
        %         if app.IncludevalidationCheckBox.Value == 1
        %
        %             legend(app.UIAxesLoss,["Training","Validation"])
        %         else
        %             legend(app.UIAxesLoss,["Training"])
        %
        %         end
        %
        %         % You can also plot or access previous loss values here
        %         % For example, access the previous loss (before the current iteration)
        %         if iteration > 1
        %             previousLoss = lossHistory(end-1);  % Get the previous loss
        %             disp(['Previous Loss at Iteration ', num2str(iteration - 1), ': ', num2str(previousLoss)]);
        %         end
        %
        %         % Update Accuracy Plot (if available)
        %         persistent accLine valaccLine;
        %         if ~isempty(info.TrainingAccuracy)
        %             if isempty(accLine)
        %                 accLine = animatedline('Parent', app.UIAxesAccuracy, 'LineWidth', 1, 'Color', [0 0.4470 0.7410]);
        %                 xlabel(app.UIAxesAccuracy, 'Iteration');
        %                 ylabel(app.UIAxesAccuracy, 'Training Accuracy');
        %                 if app.IncludevalidationCheckBox.Value == 1
        %                     valaccLine = animatedline('Parent', app.UIAxesAccuracy, 'LineWidth', 1, 'Color', [0.9290 0.6940 0.1250]);
        %                 end
        %             end
        %             if app.IncludevalidationCheckBox.Value == 1
        %                 if ~isempty(info.ValidationAccuracy)
        %                     addpoints(valaccLine, iteration, info.ValidationAccuracy(end));  % Add a new point for accuracy
        %                 end
        %             end
        %             addpoints(accLine, iteration, info.TrainingAccuracy(end));  % Add a new point for accuracy
        %             xlim(app.UIAxesAccuracy, [0 iteration]);  % Update the X axis limits
        %             ylim(app.UIAxesAccuracy, [0 100]);  % Accuracy is typically between 0 and 1
        %             if app.IncludevalidationCheckBox.Value == 1
        %
        %                 legend(app.UIAxesAccuracy,["Training","Validation"])
        %             else
        %                 legend(app.UIAxesAccuracy,["Training"])
        %
        %             end
        %
        %
        %             if isempty(info.Epoch)
        %                 app.TrainingwaitingtostartLabel.Text = ['Training: in progress, please wait... Epoch: 0 of ' num2str(options.MaxEpochs)];
        %             else
        %                 app.TrainingwaitingtostartLabel.Text = ['Training: in progress, please wait... Epoch: ' num2str(info.Epoch) ' of ' num2str(options.MaxEpochs)];
        %             end
        %             if isempty(info.TimeElapsed)
        %                 app.TimeelapsedLabel.Text = ['Time elapsed: ---- '];
        %             else
        %                 app.TimeelapsedLabel.Text = "Time elapsed: " + string(info.TimeElapsed);
        %             end
        %         end
        %
        %         % Ensure the UI updates smoothly
        %         drawnow;
        %         if stopTrainingFlag
        %             disp('Stopping training...');
        %             stop = true; % Stop training
        %         end
        %     end
        % end
        %
        %
        function askForFilenameAndSave(app, trainedNet)
            % Create a new UI figure (popup)
            fig = uifigure('Name', 'Save trained network', 'Position', [500 500 300 150]);

            % Add a text label
            lbl = uilabel(fig, 'Text', 'Enter filename:', 'Position', [20 100 100 20]);

            % Add an input field for the filename
            filenameField = uieditfield(fig, 'text', 'Position', [120 100 150 22]);

            % Add a save button
            btn = uibutton(fig, 'push', 'Text', 'Save', ...
                'Position', [100 50 100 30], ...
                'ButtonPushedFcn', @(btn,event) saveNetwork(app, trainedNet, filenameField.Value, fig));
        end

        function stop = updatePlot(app, info)
            global alert
            global stopTrainingFlag;
            stop = false; % Allow training to continue
            global options flag

            % Flag to detect if it's a new session
            persistent  lossHistory iterationHistory vallossHistory lossLine valLossLine;
            global isNewSession
            % Check if this is the beginning of a new session
            if isempty(isNewSession) || isNewSession
                % Reset the history variables at the start of the new session
                lossHistory = [];
                iterationHistory = [];
                vallossHistory = [];
                delete(lossLine);% = [];  % Reset the lossLine handle
                lossLine = animatedline('Parent', app.UIAxesLoss, 'LineWidth', 1, 'Color', [0 0.4470 0.7410]);
                xlabel(app.UIAxesLoss, 'Iteration');
                ylabel(app.UIAxesLoss, 'Loss');
                if app.IncludevalidationCheckBox.Value == 1
                    delete(valLossLine);% = [];  % Reset the validation loss line handle
                    valLossLine = animatedline('Parent', app.UIAxesLoss, 'LineWidth', 1, 'Color', [0.9290 0.6940 0.1250]);
                end
                isNewSession = false; % Mark that session has started
            end

            % Check if info contains training data (i.e., loss)
            if ~isempty(info.TrainingLoss)
                iteration = info.Iteration;

                % Initialize the history variables if they don't exist
                if isempty(lossHistory)
                    lossHistory = info.TrainingLoss;  % Store the current loss in history
                    iterationHistory = iteration;     % Store the current iteration number
                    if app.IncludevalidationCheckBox.Value == 1
                        vallossHistory = info.ValidationLoss;  % Store the current loss in history
                    end
                else
                    % Append the current loss and iteration to the history
                    lossHistory = [lossHistory, info.TrainingLoss];
                    iterationHistory = [iterationHistory, iteration];
                    if app.IncludevalidationCheckBox.Value == 1
                        vallossHistory = [vallossHistory, info.ValidationLoss];  % Store the current loss in history
                    end
                end

                % Debugging: Display previous loss values
                disp('Previous Loss Values:');
                disp(lossHistory);

                % Update Loss Plot using animatedline
                if isempty(lossLine)  % Initialize loss line if it's the start of a new session
                    lossLine = animatedline('Parent', app.UIAxesLoss, 'LineWidth', 1, 'Color', [0 0.4470 0.7410]);
                    xlabel(app.UIAxesLoss, 'Iteration');
                    ylabel(app.UIAxesLoss, 'Loss');
                    if app.IncludevalidationCheckBox.Value == 1
                        valLossLine = animatedline('Parent', app.UIAxesLoss, 'LineWidth', 1, 'Color', [0.9290 0.6940 0.1250]);
                    end
                end
                try
                    addpoints(lossLine, iteration, info.TrainingLoss(end));  % Add new point for loss
                catch
                    disp('tu!')
                end
                if app.IncludevalidationCheckBox.Value == 1
                    if ~isempty(info.ValidationLoss)
                        addpoints(valLossLine, iteration, info.ValidationLoss(end));  % Add new point for validation loss
                    end
                end
                xlim(app.UIAxesLoss, [0 iteration]);  % Update the X axis limits

                if app.IncludevalidationCheckBox.Value == 1
                    ylim(app.UIAxesLoss, [0 max([lossHistory, vallossHistory])]);  % Adjust Y axis dynamically based on loss history
                else
                    ylim(app.UIAxesLoss, [0 max(lossHistory)]);  % Adjust Y axis dynamically based on loss history
                end

                if app.IncludevalidationCheckBox.Value == 1
                    legend(app.UIAxesLoss, ["Training", "Validation"]);
                else
                    legend(app.UIAxesLoss, ["Training"]);
                end

                % Update Accuracy Plot (if available)
                persistent accLine valaccLine;
                if ~isempty(info.TrainingAccuracy)
                    if isempty(accLine)  % Initialize accuracy line if it's the start of a new session
                        accLine = animatedline('Parent', app.UIAxesAccuracy, 'LineWidth', 1, 'Color', [0 0.4470 0.7410]);
                        xlabel(app.UIAxesAccuracy, 'Iteration');
                        ylabel(app.UIAxesAccuracy, 'Training Accuracy');
                        if app.IncludevalidationCheckBox.Value == 1
                            valaccLine = animatedline('Parent', app.UIAxesAccuracy, 'LineWidth', 1, 'Color', [0.9290 0.6940 0.1250]);
                        end
                    end
                    if app.IncludevalidationCheckBox.Value == 1
                        if ~isempty(info.ValidationAccuracy)
                            try
                            addpoints(valaccLine, iteration, info.ValidationAccuracy(end));  % Add a new point for validation accuracy
                            catch
                                valaccLine = animatedline('Parent', app.UIAxesAccuracy, 'LineWidth', 1, 'Color', [0.9290 0.6940 0.1250]);
                            end
                        end
                    end
                    addpoints(accLine, iteration, info.TrainingAccuracy(end));  % Add a new point for training accuracy
                    xlim(app.UIAxesAccuracy, [0 iteration]);  % Update the X axis limits
                    ylim(app.UIAxesAccuracy, [0 100]);  % Accuracy is typically between 0 and 1
                    if app.IncludevalidationCheckBox.Value == 1
                        legend(app.UIAxesAccuracy, ["Training", "Validation"]);
                    else
                        legend(app.UIAxesAccuracy, ["Training"]);
                    end

                    % Update the UI with elapsed time and epoch information
                    if isempty(info.Epoch)
                        app.TrainingwaitingtostartLabel.Text = ['Training: in progress, please wait... Epoch: 0 of ' num2str(options.MaxEpochs)];
                    else
                        app.TrainingwaitingtostartLabel.Text = ['Training: in progress, please wait... Epoch: ' num2str(info.Epoch) ' of ' num2str(options.MaxEpochs)];
                    end
                    if contains(flag, 'Classification')
                        if isempty(info.TimeElapsed)
                            app.TimeelapsedLabel.Text = ['Time elapsed: ---- '];
                        else
                            app.TimeelapsedLabel.Text = "Time elapsed: " + string(info.TimeElapsed);
                        end
                    else
                        app.TimeelapsedLabel.Text = "Time elapsed: " + string(info.TimeSinceStart);
                    end
                end

                % Ensure the UI updates smoothly
                drawnow;

                % Check stop condition for training
                if stopTrainingFlag
                    disp('Stopping training...');
                    app.TrainingwaitingtostartLabel.Text = ['Training: stopped by user. Epoch: ' num2str(info.Epoch) ' of ' num2str(options.MaxEpochs)];
                    stop = true; % Stop training
                end
            end
        end


        function saveNetwork(app, trainedNet, filename, fig)
            if isempty(filename)
                uialert(app.DeepLearningAppUIFigure, 'Filename cannot be empty!', 'Error', 'Icon', 'error');
                return;
            end

            % Ensure filename ends with .mat
            if ~endsWith(filename, '.mat')
                filename = strcat(filename, '.mat');
            end

            % Save the trained network
            save(['./models/' filename], 'trainedNet');

            % Close the popup window
            close(fig);

            % Show a confirmation message
            uialert(app.DeepLearningAppUIFigure, ['Network saved as ', filename], 'Success', 'Icon', 'success');
        end


        function data = customReadFcn(app, filename)
            % Read the image
            global flag
            img = imread(filename);
            if contains(flag, "Classification")
                % Define the target size
                targetSize = [app.ImagesizeEditField_x.Value, app.ImagesizeEditField_y.Value]; % Change to your desired dimensions
            elseif contains(flag, "Segmentation")
                % Resize the image
                targetSize = [app.ImagesizeEditField_x_s.Value, app.ImagesizeEditField_y_s.Value]; % Change to your desired dimensions

            end
            data = imresize(img, targetSize);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            layers = [imageInputLayer([round(app.ImagesizeEditField_x.Value) round(app.ImagesizeEditField_y.Value) round(app.ImagesizeEditField_z.Value)]) % Minimal input layer

            convolution2dLayer(3,8,Padding="same")
            batchNormalizationLayer
            reluLayer

            maxPooling2dLayer(2,Stride=2)

            convolution2dLayer(3,16,Padding="same")
            batchNormalizationLayer
            reluLayer

            maxPooling2dLayer(2,Stride=2)

            convolution2dLayer(3,32,Padding="same")
            batchNormalizationLayer
            reluLayer

            fullyConnectedLayer(round(app.NumberofclassesEditField_2.Value))
            softmaxLayer];

            model2use = dlnetwork(layerGraph(layers));
            lg = layerGraph(model2use);
            fig = figure;
            plot(lg);
            ax = gca;  % Get the current axes of the figure
            copyobj(allchild(ax), app.UIAxesModel);
            close(fig)
        end

        % Callback function
        function PretrainedButtonGroupSelectionChanged(app, event)
            global model2use
            selectedButton = app.PretrainedButtonGroup.SelectedObject;
            if contains(selectedButton.Text,'AlexNet')
                model2use = alexnet();
            elseif contains(selectedButton.Text,'Inception')
                model2use = inceptionv3();
            elseif contains(selectedButton.Text,'MobileNet')
                model2use = mobilenetv2();
            end
        end

        % Callback function
        function PretrainedButtonGroupButtonDown(app, event)
            global model2use
            selectedButton = app.PretrainedButtonGroup.SelectedObject;
            if contains(selectedButton.Text,'AlexNet')
                model2use = alexnet();
            elseif contains(selectedButton.Text,'Inception')
                model2use = inceptionv3();
            elseif contains(selectedButton.Text,'MobileNet')
                model2use = mobilenetv2();
            end
        end

        % Callback function
        function CustomButtonGroupSelectionChanged(app, event)

        end

        % Callback function
        function CustomButtonGroupButtonDown(app, event)

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            global model2use flag
            selectedTab = app.TabGroup2.SelectedTab;
            flag = selectedTab.Title;

            if contains(flag, 'Classification')
                selectedButton = app.SelectmodelButtonGroup.SelectedObject;
                % app.LabelssourcefoldernamesCheckBox.Enable = 'on';
                % app.LabelssourcetableCheckBox.Enable = 'on';
                app.LoaddataButton.Tooltip = 'Indicate path to data directory.';

                if contains(selectedButton.Text,'Simple classification network')
                    cla(app.UIAxesModel)
                    app.ImagesizeEditField_x.Enable = 'on';
                    app.ImagesizeEditField_y.Enable = 'on';
                    app.ImagesizeEditField_z.Enable = 'on';
                    layers = [imageInputLayer([round(app.ImagesizeEditField_x.Value) round(app.ImagesizeEditField_y.Value) round(app.ImagesizeEditField_z.Value)]) % Minimal input layer

                    convolution2dLayer(3,8,Padding="same")
                    batchNormalizationLayer
                    reluLayer

                    maxPooling2dLayer(2,Stride=2)

                    convolution2dLayer(3,16,Padding="same")
                    batchNormalizationLayer
                    reluLayer

                    maxPooling2dLayer(2,Stride=2)

                    convolution2dLayer(3,32,Padding="same")
                    batchNormalizationLayer
                    reluLayer

                    fullyConnectedLayer(round(app.NumberofclassesEditField_2.Value))
                    softmaxLayer];
                    model2use = layers;

                    lg = layerGraph(model2use);
                    fig = figure;
                    plot(lg);
                    ax = gca;  % Get the current axes of the figure
                    copyobj(allchild(ax), app.UIAxesModel);
                    close(fig)
                    title(app.UIAxesModel, 'Network architecture');
                    classification_network = model2use;

                elseif contains(selectedButton.Text,'AlexNet')
                    classification_network = model2use;
                    % if app.IncludeweightsCheckBox.Value == 1
                    %     model2use = alexnet(Weights='imagenet'); %imagePretrainedNetwork("alexnet",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="pretrained");
                    % else
                    %     model2use = alexnet(Weights='none'); %imagePretrainedNetwork("alexnet",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="none");
                    % end
                    % deepNetworkDesigner(model2use);
                elseif contains(selectedButton.Text,'Inceptionv3')
                    classification_network = model2use;
                    % if app.IncludeweightsCheckBox.Value == 1
                    %     model2use = imagePretrainedNetwork("inceptionv3",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="pretrained");
                    % else
                    %     model2use = imagePretrainedNetwork("inceptionv3",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="none");
                    % end
                    % deepNetworkDesigner(model2use);
                elseif contains(selectedButton.Text,'MobileNetv2')
                    classification_network = model2use;
                    % if app.IncludeweightsCheckBox.Value == 1
                    %     model2use = imagePretrainedNetwork("mobilenetv2",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="pretrained");
                    % else
                    %     model2use = imagePretrainedNetwork("mobilenetv2",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="none");
                    % end
                    % deepNetworkDesigner(model2use);
                elseif contains(selectedButton.Text,'ResNet-50')
                    classification_network = model2use;
                    % if app.IncludeweightsCheckBox.Value == 1
                    %     model2use = imagePretrainedNetwork("resnet50",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="pretrained");
                    % else
                    %     model2use = imagePretrainedNetwork("resnet50",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="none");
                    % end
                    % deepNetworkDesigner(model2use);
                elseif contains(selectedButton.Text,'DeepLabv3+')
                    classification_network = model2use;
                    % if contains(app.BackboneDropDown.Value, 'ResNet50')
                    %     bckbone = "resnet50";
                    % end
                    % model2use = deeplabv3plusLayers([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)], round(app.NumberofclassesEditField.Value), bckbone);
                    % layerNames = {model2use.Layers.Name}; % Get all layer names
                    % if any(strcmp(layerNames, 'Segmentation-Layer'))
                    %     disp('Layer exists in the network.');
                    %     model2use = removeLayers(model2use, 'Segmentation-Layer'); % Replace with actual layer name
                    % else
                    %     disp('Layer not found.');
                    % end
                    % deepNetworkDesigner(dlnetwork(model2use));
                elseif contains(selectedButton.Text,'UNet')
                    classification_network = model2use;
                    % model2use = unetLayers([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)], round(app.NumberofclassesEditField.Value));
                    % layerNames = {model2use.Layers.Name}; % Get all layer names
                    % if any(strcmp(layerNames, 'Segmentation-Layer'))
                    %     disp('Layer exists in the network.');
                    %     model2use = removeLayers(model2use, 'Segmentation-Layer'); % Replace with actual layer name
                    % else
                    %     disp('Layer not found.');
                    % end
                    % model2use = unet([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)], round(app.NumberofclassesEditField.Value));
                    % deepNetworkDesigner(dlnetwork(model2use));
                    % deepNetworkDesigner((model2use));
                end
                assignin('base', 'classification_network', classification_network)
            elseif contains(flag, 'Segmentation')
                selectedButton = app.SelectmodelButtonGroup_2.SelectedObject;
                % app.LabelssourcefoldernamesCheckBox.Enable = 'off';
                % app.LabelssourcetableCheckBox.Enable = 'off';

                selectedButton = app.SelectmodelButtonGroup_2.SelectedObject;
                if contains(selectedButton.Text,'Simple segmentation network')
                    layers = [
                        imageInputLayer([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)])
                        convolution2dLayer([3,3],64,Padding=[1,1,1,1])
                        reluLayer
                        maxPooling2dLayer([2,2],Stride=[2,2])
                        convolution2dLayer([3,3],64,Padding=[1,1,1,1])
                        reluLayer
                        transposedConv2dLayer([4,4],64,Stride=[2,2],Cropping=[1,1,1,1])
                        convolution2dLayer([1,1],3)
                        softmaxLayer];
                    model2use = layerGraph(layers);
                    app.BackboneDropDown.Enable = 'off';

                    lg = layerGraph(model2use);
                    fig = figure;
                    plot(lg);
                    ax = gca;  % Get the current axes of the figure
                    copyobj(allchild(ax), app.UIAxesModel);
                    close(fig)
                    segmentation_network = dlnetwork(model2use);

                elseif contains(selectedButton.Text,'DeepLabv3+')
                    segmentation_network = dlnetwork(model2use);

                elseif contains(selectedButton.Text,'UNet')
                    segmentation_network = dlnetwork(model2use);

                end
                app.LoaddataButton.Tooltip = 'Indicate path to directory with folders named: "images" and "masks".';
                assignin('base', 'segmentation_network', segmentation_network)
            elseif contains(flag, 'Detection')
                selectedButton = app.SelectmodelButtonGroup_3.SelectedObject;
                % app.LabelssourcefoldernamesCheckBox.Enable = 'off';
                % app.LabelssourcetableCheckBox.Enable = 'off';
                app.LoaddataButton.Tooltip = '';
            end


            if isempty(model2use)
                % msgbox('Model is empty. Please choose an architecture.')
                uialert(app.DeepLearningAppUIFigure, ['Model is empty. Please choose an architecture.'], '', 'Icon', 'error');
            else
                % msgbox('Please wait.')
                uialert(app.DeepLearningAppUIFigure, ['Network selected.'], '', 'Icon', 'success');
                % deepNetworkDesigner(model2use);
            end
        end

        % Selection changed function: SelectmodelButtonGroup
        function SelectmodelButtonGroupSelectionChanged(app, event)
            global model2use
            selectedButton = app.SelectmodelButtonGroup.SelectedObject;
            if contains(selectedButton.Text,'Simple classification network')
                cla(app.UIAxesModel)
                app.ImagesizeEditField_x.Enable = 'on';
                app.ImagesizeEditField_y.Enable = 'on';
                app.ImagesizeEditField_z.Enable = 'on';
                layers = [imageInputLayer([round(app.ImagesizeEditField_x.Value) round(app.ImagesizeEditField_y.Value) round(app.ImagesizeEditField_z.Value)]) % Minimal input layer

                convolution2dLayer(3,8,Padding="same")
                batchNormalizationLayer
                reluLayer

                maxPooling2dLayer(2,Stride=2)

                convolution2dLayer(3,16,Padding="same")
                batchNormalizationLayer
                reluLayer

                maxPooling2dLayer(2,Stride=2)

                convolution2dLayer(3,32,Padding="same")
                batchNormalizationLayer
                reluLayer

                fullyConnectedLayer(round(app.NumberofclassesEditField_2.Value))
                softmaxLayer];
                model2use = layers;

                lg = layerGraph(model2use);
                fig = figure;
                plot(lg);
                ax = gca;  % Get the current axes of the figure
                copyobj(allchild(ax), app.UIAxesModel);
                close(fig)
                title(app.UIAxesModel, 'Network architecture');


                %deepNetworkDesigner(net);
            elseif contains(selectedButton.Text,'AlexNet')
                cla(app.UIAxesModel)
                app.ImagesizeEditField_x.Value = 227;
                app.ImagesizeEditField_y.Value = 227;
                app.ImagesizeEditField_z.Value = 3;
                app.ImagesizeEditField_x.Enable = 'off';
                app.ImagesizeEditField_y.Enable = 'off';
                app.ImagesizeEditField_z.Enable = 'off';
                model2use = alexnet();
                % model2use = layerGraph(model2use.Layers);

                lg = layerGraph(model2use);
                fig = figure;
                plot(lg);
                ax = gca;  % Get the current axes of the figure
                copyobj(allchild(ax), app.UIAxesModel);
                close(fig)
                title(app.UIAxesModel, 'Network architecture');
            elseif contains(selectedButton.Text,'Inceptionv3')
                model2use = inceptionv3(); %imagePretrainedNetwork("inceptionv3",NumClasses=round(app.NumberofclassesEditField_2.Value));
                lg = layerGraph(model2use);
                fig = figure;
                plot(lg);
                ax = gca;  % Get the current axes of the figure
                copyobj(allchild(ax), app.UIAxesModel);
                close(fig)
                title(app.UIAxesModel, 'Network architecture');

            elseif contains(selectedButton.Text,'MobileNetv2')
                model2use = mobilenetv2(); %imagePretrainedNetwork("inceptionv3",NumClasses=round(app.NumberofclassesEditField_2.Value));
                lg = layerGraph(model2use);
                fig = figure;
                plot(lg);
                ax = gca;  % Get the current axes of the figure
                copyobj(allchild(ax), app.UIAxesModel);
                close(fig)
                title(app.UIAxesModel, 'Network architecture');
            elseif contains(selectedButton.Text,'ResNet-50')
                model2use = resnet50(); %imagePretrainedNetwork("inceptionv3",NumClasses=round(app.NumberofclassesEditField_2.Value));
                lg = layerGraph(model2use);
                fig = figure;
                plot(lg);
                ax = gca;  % Get the current axes of the figure
                copyobj(allchild(ax), app.UIAxesModel);
                close(fig)
                title(app.UIAxesModel, 'Network architecture');
            end
        end

        % Callback function
        function LoaddatapartitionedinfoldersButtonPushed(app, event)

        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            global imds flag imdsLabels ifdataloaded_success
            dname = uigetdir('C:\');

            dirPath = dname;

            % Check if 'images' and 'masks' folders exist
            imagesFolderExists = isfolder(fullfile(dirPath, 'images'));
            masksFolderExists = isfolder(fullfile(dirPath, 'masks'));
            
            % Display the result
            if imagesFolderExists && masksFolderExists
                flag = 'Segmentation';
                  % if app.LabelssourcefoldernamesCheckBox.Value == 1
                imds = imageDatastore(dname, ...
                    'IncludeSubfolders',true,'LabelSource','foldernames', 'ReadFcn', @app.customReadFcn);
                % else
                % imds = imageDatastore(dname, ...
                % 'LabelSource','none','ReadFcn', @customReadFcn);
                % end
                uialert(app.DeepLearningAppUIFigure, ['Data loaded.'], 'Success', 'Icon', 'success');
                ifdataloaded_success = 1;
            else
                flag = 'Classification';
                  % if app.LabelssourcefoldernamesCheckBox.Value == 1
                imds = imageDatastore(dname, ...
                    'IncludeSubfolders',true,'LabelSource','foldernames', 'ReadFcn', @app.customReadFcn);
                % else
                % imds = imageDatastore(dname, ...
                % 'LabelSource','none','ReadFcn', @customReadFcn);
                % end
                uialert(app.DeepLearningAppUIFigure, ['Data loaded.'], 'Success', 'Icon', 'success');
                ifdataloaded_success = 1;
            end
        end

        % Callback function
        function LabelssourcefoldernamesCheckBoxValueChanged(app, event)
            value = app.LabelssourcefoldernamesCheckBox.Value;
            if value == 1
                app.LabelssourcetableCheckBox.Value = 0;
                app.ImporttableButton.Enable = 'off';
            else
                app.LabelssourcetableCheckBox.Value = 1;
                app.ImporttableButton.Enable = 'on';
            end
        end

        % Callback function
        function LabelssourcetableCheckBoxValueChanged(app, event)
            value = app.LabelssourcetableCheckBox.Value;
            if value == 1
                app.LabelssourcefoldernamesCheckBox.Value = 0;
                app.ImporttableButton.Enable = 'on';
            else
                app.LabelssourcefoldernamesCheckBox.Value = 1;
                app.ImporttableButton.Enable = 'off';
            end
        end

        % Callback function
        function ImporttableButtonPushed(app, event)
            global labelsTab
            [file,path] = uigetfile('C:\');
            labelsTab = readtable([path '\' file]);
        end

        % Button pushed function: ApplypartitioningButton
        function ApplypartitioningButtonPushed(app, event)
            global imds imdsTrain imdsVal imdsTest imgsTr imgsVl imgsTs flag imdsLabels
            if app.IncludevalidationCheckBox.Value == 1

                if (app.TraindatasetEditField.Value+app.TestdatasetEditField.Value + app.ValidationdatasetEditField.Value) ~= 1
                    % msgbox('Ratios does not sum to 1. Make corrections.')
                    uialert(app.DeepLearningAppUIFigure, ['Ratios does not sum to 1. Make corrections.'], '', 'Icon', 'error');
                else

                    if contains(flag, 'Classification')
                        [imdsTrain, imdsVal, imdsTest] = splitEachLabel(imds,app.TraindatasetEditField.Value,app.ValidationdatasetEditField.Value);
                        histrTrain = histogram(app.UIAxes, imdsTrain.Labels, "FaceColor",[1 0 0],"EdgeColor","none");
                        app.TransparencySlider.Visible = 'off';
                        app.TransparencySlider_2.Visible = 'off';
                        app.TransparencySlider_3.Visible = 'off';
                        app.UIAxes.Visible = 'On';
                        app.UIAxes_2.Visible = 'on';
                        app.UIAxes_3.Visible = 'on';
                        app.Label.Visible = 'on';
                        app.Label_2.Visible = 'on';
                        app.Label_3.Visible = 'on';
                        app.ClassLabel.Visible = 'on'; app.ClassLabel.Text = "Class: ";
                        app.ClassLabel_2.Visible = 'on'; app.ClassLabel_2.Text = "Class: ";
                        app.ClassLabel_3.Visible = 'on'; app.ClassLabel_3.Text = "Class: ";

                        app.UIAxes2.Position = [453,81,327,185];
                        app.Slider.Position = [452,46,316,3];

                        histrVal = histogram(app.UIAxes_2, imdsVal.Labels, "FaceColor",[1 0 0],"EdgeColor","none");
                        histrTest = histogram(app.UIAxes_3, imdsTest.Labels, "FaceColor",[1 0 0],"EdgeColor","none");
                        %imgsTr = readall(imdsTrain);
                        %imgsVl = readall(imdsVal);
                        %imgsTs = readall(imdsTest);
                        clear imgsTr imgsVl imgsTs
                        index = 1;  % Specify the index of the image you want to read (e.g., 5th image)
                        imageFilePathTr = imdsTrain.Files{index};
                        imgsTr = imread(imageFilePathTr);

                        imshow(imgsTr, 'Parent', app.UIAxes2)
                        app.Label.Text = [string(imdsTrain.Labels(1))];
                        app.Slider.Value = 1
                        app.Slider.Limits = [1 length(imdsTrain.Files)]

                        imageFilePathVl = imdsVal.Files{index};
                        imgsVl = imread(imageFilePathVl);
                        imshow(imgsVl, 'Parent', app.UIAxes2_2)
                        app.Label_2.Text = [string(imdsVal.Labels(1))];
                        app.Slider_2.Value = 1
                        app.Slider_2.Limits = [1 length(imdsVal.Files)]

                        imageFilePathTs = imdsTrain.Files{index};
                        imgsTs = imread(imageFilePathTs);
                        imshow(imgsTs, 'Parent', app.UIAxes2_3)
                        app.Label_3.Text = [string(imdsTest.Labels(1))];
                        app.Slider_3.Value = 1
                        app.Slider_3.Limits = [1 length(imdsTest.Files)]
                    elseif contains(flag, 'Segmentation')
                        trainRatio = app.TraindatasetEditField.Value;
                        valRatio = app.ValidationdatasetEditField.Value;
                        testRatio = app.TestdatasetEditField.Value;

                        % Count total number of images
                        numImages = numel(imds.Files);
                        numTrain = round(trainRatio * numImages);
                        numVal = round(valRatio * numImages);
                        numTest = numImages - (numTrain + numVal); % Ensure full coverage

                        % Shuffle indices for randomness
                        idx = randperm(numImages);
                        global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                        % Split imageDatastore
                        imdsTrain_im = subset(imds, idx(1:numTrain));
                        imdsVal_im   = subset(imds, idx(numTrain+1:numTrain+numVal));
                        imdsTest_im  = subset(imds, idx(numTrain+numVal+1:end));

                        % Split pixelLabelDatastore (for segmentation masks)
                        pxdsTrain = subset(imdsLabels, idx(1:numTrain));
                        pxdsVal   = subset(imdsLabels, idx(numTrain+1:numTrain+numVal));
                        pxdsTest  = subset(imdsLabels, idx(numTrain+numVal+1:end));

                        % Combine images and labels to maintain alignment
                        imdsTrain = combine(imdsTrain_im, pxdsTrain);
                        imdsVal = combine(imdsVal_im, pxdsVal);
                        imdsTest = combine(imdsTest_im, pxdsTest);

                        app.TransparencySlider.Visible = 'on';
                        app.TransparencySlider_2.Visible = 'on';
                        app.TransparencySlider_3.Visible = 'on';

                        app.UIAxes.Visible = 'off';
                        app.UIAxes_2.Visible= 'off';
                        app.UIAxes_3.Visible = 'off';
                        app.Label.Visible = 'off';
                        app.Label_2.Visible = 'off';
                        app.Label_3.Visible = 'off';
                        app.ClassLabel.Visible = 'off';
                        app.ClassLabel_2.Visible = 'off';
                        app.ClassLabel_3.Visible = 'off';

                        clear imdsTrain_im_ra imdsVal_im_ra imdsTest_im_ra
                        % imdsTrain_im_ra = readall(imdsTrain_im);
                        % imdsVal_im_ra = readall(imdsVal_im);
                        % imdsTest_im_ra = readall(imdsTest_im);
                        %
                        % pxdsTrain_ra = readall(pxdsTrain);
                        % pxdsVal_ra = readall(pxdsVal);
                        % pxdsTest_ra = readall(pxdsTest);

                        index = 1;  % Specify the index of the image you want to read (e.g., 5th image)
                        imageFilePathTr = imdsTrain_im.Files{index};
                        imdsTrain_im_ra = imread(imageFilePathTr);

                        imageFilePathVal = imdsVal_im.Files{index};
                        imdsVal_im_ra = imread(imageFilePathVal);

                        imageFilePathTest = imdsTest_im.Files{index};
                        imdsTest_im_ra = imread(imageFilePathTest);

                        pixFilePathTr = pxdsTrain.Files{index};
                        pxdsTrain_ra = imread(pixFilePathTr);
                        pixFilePathVal = pxdsVal.Files{index};
                        pxdsVal_ra = imread(pixFilePathVal);
                        pixFilePathTest = pxdsTest.Files{index};
                        pxdsTest_ra = imread(pixFilePathTest);

                        app.UIAxes2.Position = [260,48,261,218]; %453,81,327,185
                        app.Slider.Position = [260,48,261,218]; % 452,46,316,3
                        app.TransparencySlider.Position = [550,80,150,140]; % 452,46,316,3
                        app.TransparencySliderLabel.Position = [460,170,150,140];
                        imshow(labeloverlay(imdsTrain_im_ra, pxdsTrain_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2)

                        app.Slider.Value = 1
                        app.Slider.Limits = [1 length(imdsTrain_im.Files)]

                        app.UIAxes2_2.Position = [260,48,261,218]; %453,81,327,185
                        app.Slider_2.Position = [260,48,261,218]; % 452,46,316,3
                        app.TransparencySlider_2.Position = [550,80,150,140]; % 452,46,316,3
                        app.TransparencySlider_2Label.Position = [460,170,150,140];
                        imshow(labeloverlay(imdsVal_im_ra, pxdsVal_ra, 'Transparency', app.TransparencySlider_2.Value), 'Parent', app.UIAxes2_2)
                        app.Slider_2.Value = 1
                        app.Slider_2.Limits = [1 length(imdsVal_im.Files)]

                        app.UIAxes2_3.Position = [260,48,261,218]; %453,81,327,185
                        app.Slider_3.Position = [260,48,261,218]; % 452,46,316,3
                        app.TransparencySlider_3.Position = [550,80,150,140]; % 452,46,316,3
                        app.TransparencySlider_3Label.Position = [460,170,150,140];
                        imshow(labeloverlay(imdsTest_im_ra, pxdsTest_ra, 'Transparency', app.TransparencySlider_3.Value), 'Parent', app.UIAxes2_3)
                        app.Slider_3.Value = 1
                        app.Slider_3.Limits = [1 length(imdsTest_im.Files)]

                    end
                end


            else
                if (app.TraindatasetEditField.Value+app.TestdatasetEditField.Value) ~= 1
                    % msgbox('Ratios does not sum to 1. Make corrections.')
                    uialert(app.DeepLearningAppUIFigure, ['Ratios does not sum to 1. Make corrections.'], '', 'Icon', 'error');
                else
                    [imdsTrain, imdsTest] = splitEachLabel(imds,app.TraindatasetEditField.Value);
                    imdsVal = [];
                    histrTrain = histogram(app.UIAxes, imdsTrain.Labels, "FaceColor",[1 0 0],"EdgeColor","none");
                    app.UIAxes.Visible = 'Off';
                    histrTest = histogram(app.UIAxes_3, imdsTest.Labels, "FaceColor",[1 0 0],"EdgeColor","none");
                end
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest flag
            value = app.Slider.Value;
            if contains(flag, 'Classification')
                % imgsTr = readall(imdsTrain);
                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTr = imdsTrain.Files{index};
                imgsTr = imread(imageFilePathTr);
                imshow(imgsTr, 'Parent', app.UIAxes2)
                app.Label.Text = [string(imdsTrain.Labels(round(value)))];
            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible= 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';


                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTr = imdsTrain_im.Files{index};
                imdsTrain_im_ra = imread(imageFilePathTr);

                pixFilePathTr = pxdsTrain.Files{index};
                pxdsTrain_ra = imread(pixFilePathTr);

                imshow(labeloverlay(imdsTrain_im_ra, pxdsTrain_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2)
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Classification')
                % imgsTr = readall(imdsTrain);
                index = round(changingValue);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTr = imdsTrain.Files{index};
                imgsTr = imread(imageFilePathTr);
                imshow(imgsTr, 'Parent', app.UIAxes2)
                app.Label.Text = [string(imdsTrain.Labels(round(changingValue)))];
            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible= 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(changingValue);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTr = imdsTrain_im.Files{index};
                imdsTrain_im_ra = imread(imageFilePathTr);

                pixFilePathTr = pxdsTrain.Files{index};
                pxdsTrain_ra = imread(pixFilePathTr);
                imshow(labeloverlay(imdsTrain_im_ra, pxdsTrain_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2)
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Classification')
                % imgsVl = readall(imdsVal);
                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathVl = imdsVal.Files{index};
                imgsVl = imread(imageFilePathVl);
                imshow(imgsVl, 'Parent', app.UIAxes2_2)
                app.Label_2.Text = [string(imdsVal.Labels(round(value)))];

            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible= 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathVal = imdsVal_im.Files{index};
                imdsVal_im_ra = imread(imageFilePathVal);
                pixFilePathVal = pxdsVal.Files{index};
                pxdsVal_ra = imread(pixFilePathVal);

                imshow(labeloverlay(imdsVal_im_ra, pxdsVal_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2_2)
            end

        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Classification')
                % imgsVl = readall(imdsVal);
                index = round(changingValue);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathVl = imdsVal.Files{index};
                imgsVl = imread(imageFilePathVl);
                imshow(imgsVl, 'Parent', app.UIAxes2_2)
                app.Label_2.Text = [string(imdsVal.Labels(1))];

            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible= 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(changingValue);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathVal = imdsVal_im.Files{index};
                imdsVal_im_ra = imread(imageFilePathVal);
                pixFilePathVal = pxdsVal.Files{index};
                pxdsVal_ra = imread(pixFilePathVal);

                imshow(labeloverlay(imdsVal_im_ra, pxdsVal_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2_2)
            end
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Classification')
                % imgsTs = readall(imdsTest);
                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTs = imdsTest.Files{index};
                imgsTs = imread(imageFilePathTs);
                imshow(imgsTs, 'Parent', app.UIAxes2_3)
                app.Label_3.Text = [string(imdsTest.Labels(round(value)))];
            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible= 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTest = imdsTest_im.Files{index};
                imdsTest_im_ra = imread(imageFilePathTest);
                pixFilePathTest = pxdsTest.Files{index};
                pxdsTest_ra = imread(pixFilePathTest);

                imshow(labeloverlay(imdsTest_im_ra, pxdsTest_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2_3)
            end

        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Classification')
                % imgsTs = readall(imdsTest);
                index = round(changingValue);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTs = imdsTest.Files{index};
                imgsTs = imread(imageFilePathTs);
                imshow(imgsTs, 'Parent', app.UIAxes2_3)
                app.Label_3.Text = [string(imdsTest.Labels(round(changingValue)))];
            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible= 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(changingValue);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTest = imdsTest_im.Files{index};
                imdsTest_im_ra = imread(imageFilePathTest);
                pixFilePathTest = pxdsTest.Files{index};
                pxdsTest_ra = imread(pixFilePathTest);

                imshow(labeloverlay(imdsTest_im_ra, pxdsTest_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2_3)
            end
        end

        % Button down function: DataaugmentationTab
        function DataaugmentationTabButtonDown(app, event)
            % msgbox('Please wait...')
            % tabs_da = readtable('./dataaugment.xlsx');
            % app.UITable.Data = tabs_da;

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            global ifdataloaded_success
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest augmenter model2use flag imds

            selectedTab = app.TabGroup.SelectedTab;
            if contains(selectedTab.Title, 'Data augmentation')
                tabs_da = readtable([pwd '\hyperparams\dataaugment.xlsx']);
                idx = find(contains(tabs_da.Apply,'true'));
                for ix = 1:length(idx)
                    tabs_da.Apply{idx(ix)} = true;
                end
                app.UITable.Data = tabs_da;
                if ifdataloaded_success == 1
                    augmenterSettings = struct(); % Initialize an empty struct

                    for ix = 1:length(app.UITable.Data.Apply)
                        if app.UITable.Data.Apply{ix} == 1
                            data_f = app.UITable.Data.Value{ix};
                            data_f = data_f(find(~isspace(data_f)));
                            data_f = strsplit(data_f, ',');
                            if length(data_f) > 1
                                val_data = [str2num(data_f{1}) str2num(data_f{2})];
                            else
                                val_data = str2num(data_f{1});
                            end
                            augmenterSettings(1).(app.UITable.Data.Operation{ix}) = val_data;
                        end
                    end

                    augmenter = imageDataAugmenter(augmenterSettings);
                    auimds = augmentedImageDatastore([126 126 3], imds, 'DataAugmentation', augmenter)
                    dataOut = read(auimds);
                    dataOut_in = dataOut.input;
                    imshow(dataOut_in{1}, 'Parent', app.UIAxes4_10)
                    imshow(dataOut_in{2}, 'Parent', app.UIAxes4_11)
                    imshow(dataOut_in{3}, 'Parent', app.UIAxes4_12)
                    imshow(dataOut_in{4}, 'Parent', app.UIAxes4_13)
                    imshow(dataOut_in{5}, 'Parent', app.UIAxes4_14)
                    imshow(dataOut_in{6}, 'Parent', app.UIAxes4_15)
                    if contains(flag, 'Classification')
                        imshow(dataOut_in{1}, 'Parent', app.UIAxes4)
                        imshow(dataOut_in{2}, 'Parent', app.UIAxes4_2)
                        imshow(dataOut_in{3}, 'Parent', app.UIAxes4_3)
                        imshow(dataOut_in{4}, 'Parent', app.UIAxes4_4)
                        imshow(dataOut_in{5}, 'Parent', app.UIAxes4_5)
                        imshow(dataOut_in{6}, 'Parent', app.UIAxes4_6)

                        imshow(dataOut_in{7}, 'Parent', app.UIAxes4_7)
                    else

                    end
                else
                    uialert(app.DeepLearningAppUIFigure, ['Load data first.'], 'Warning', 'Icon', 'warning');
                    app.TabGroup.SelectedTab = app.DataTab;
                end
                if contains(flag, 'Classification')
                    app.PanelClassification.Visible = 'on';
                    app.PanelSegmentation.Visible = 'off';
                elseif contains(flag, 'Segmentation')
                    app.PanelClassification.Visible = 'off';
                    app.PanelSegmentation.Visible = 'on';
                end
            elseif contains(selectedTab.Title, 'Data')
                global flag
                % flag = 'Classification';
                app.TransparencySlider.Visible = 'off';
                app.TransparencySliderLabel.Visible = 'off';
                app.TransparencySlider_2.Visible = 'off';
                app.TransparencySlider_2Label.Visible = 'off';
                app.TransparencySlider_3Label.Visible = 'off';
                app.TransparencySlider_3.Visible = 'off';

                [myimage, cmap, alpha] = imread("./ux/bck2.png");
                foo = imshow(myimage, cmap, 'Parent', app.UIAxes2);
                foo.AlphaData = alpha;

                [myimage, cmap, alpha] = imread("./ux/bck2.png");
                foo = imshow(myimage, cmap, 'Parent', app.UIAxes2_2);
                foo.AlphaData = alpha;

                [myimage, cmap, alpha] = imread("./ux/bck2.png");
                foo = imshow(myimage, cmap, 'Parent', app.UIAxes2_3);
                foo.AlphaData = alpha;
                % if contains(flag, 'Classification')
                %     app.ClassnamesTextArea.Enable = 'off';
                % else
                %     app.ClassnamesTextArea.Enable = 'on';
                % end
            elseif contains(selectedTab.Title, 'Train')
                xlabel(app.UIAxesLoss, 'Iterations');
                ylabel(app.UIAxesLoss, 'Loss');
                title(app.UIAxesLoss, 'Training Loss');
                hold(app.UIAxesLoss, 'on');

                xlabel(app.UIAxesAccuracy, 'Iterations');
                ylabel(app.UIAxesAccuracy, 'Accuracy');
                title(app.UIAxesAccuracy, 'Training Accuracy');
                hold(app.UIAxesAccuracy, 'on');

                varNames = evalin('base', 'who');
                % vars = whos; % Get all variables in the workspace
                % matchingVars = {vars(strcmp({vars.class}, 'dlnetwork')).name}; % Filter dlnetwork variables
                searchStr = 'net_';
                % Get all variable names in the workspace
                matchingVars = varNames(contains(varNames, searchStr));
                searchStr2 = '_network';
                % Get all variable names in the workspace
                matchingVars2 = varNames(contains(varNames, searchStr2));
                app.ModeltotrainDropDown.Items = [matchingVars; matchingVars2];

                val_opt = app.OptimizerDropDown.Value;

                if contains(val_opt, 'sgdm')
                    tabs_hp = readtable('./hyperparams/trainingOpts_sgdm.xlsx');
                    app.UITable2.Data = tabs_hp;
                elseif contains(val_opt, 'adam')
                    tabs_hp = readtable('./hyperparams/trainingOpts_adam.xlsx');
                    app.UITable2.Data = tabs_hp;
                elseif contains(val_opt, 'rmsprop')
                    tabs_hp = readtable('./hyperparams/trainingOpts_rmsprop.xlsx');
                    app.UITable2.Data = tabs_hp;
                elseif contains(val_opt, 'lbfgs')
                    tabs_hp = readtable('./hyperparams/trainingOpts_lbfgs.xlsx');
                    app.UITable2.Data = tabs_hp;
                end
            elseif contains(selectedTab.Title, 'Test')
                [myimage, cmap, alpha] = imread("./ux/bck2.png");
                % foo = imshow(myimage, cmap, 'Parent', app.UIAxesConfMat);
                % foo.AlphaData = alpha;
                foo2 = imshow(myimage, cmap, 'Parent', app.UIAxes4_8);
                foo2.AlphaData = alpha;
                % foo3 = imshow(myimage, cmap, 'Parent', app.UIAxes4_9);
                % foo3.AlphaData = alpha;
                app.UIAxes4_9.Visible = 'off';

                app.Panel.Visible = 'on';
                app.TransparencySliderGradCAN.Visible = 'off';
                % app.TransparencySlider_4Label_2.Visible = 'off';
                app.Slider_4.Visible = 'off';
                app.GroundtruthclassLabel.Visible = 'off';
                app.PredictedclassLabel.Visible = 'off';
                app.ApplyGradCAMButton.Visible = 'on';
                app.GradCAMclassDropDown.Visible = 'off';
                app.GradCAMclassDropDownLabel.Visible = 'off';
                if contains(flag, 'Classification')
                    % app.TransparencySlider_SEG.Visible = 'off';
                    % app.TransparencySlider_4Label_2.Visible = 'off';
                    app.GroundtruthclassLabel.Visible = 'on';
                    app.PredictedclassLabel.Visible = 'on';
                elseif contains(flag, 'Segmentation')
                    % app.TransparencySlider_SEG.Visible = 'on';
                    % app.TransparencySlider_4Label_2.Visible = 'on';
                    app.GroundtruthclassLabel.Visible = 'off';
                    app.PredictedclassLabel.Visible = 'off';
                end
                mods = [];
                all_models = dir(['./models/*.mat']);
                mods{1} = '';
                for jk = 2:length(all_models)+1
                    mods{jk} = all_models(jk-1).name(1:end-4);
                end

                if isempty(mods)
                    app.SelectsavedmodelDropDown.Items = {'Train your model first.'};
                    app.SelectsavedmodelDropDown.Value = 'Train your model first.';
                    app.SelectsavedmodelDropDown.Enable = 'off';
                else
                    app.SelectsavedmodelDropDown.Enable = 'on';
                    app.SelectsavedmodelDropDown.Items = mods;
                    app.SelectsavedmodelDropDown.Value = '';
                end
            elseif contains(selectedTab.Title, 'Network insides')

                app.FeaturemapnumberSlider.Visible = 'off';
                app.FeaturemapnumberSliderLabel.Visible = 'off';

                [myimage, cmap, alpha] = imread("./ux/bck2.png");
                foo = imshow(myimage, cmap, 'Parent', app.UIAxesII);
                foo.AlphaData = alpha;
                [myimage, cmap, alpha] = imread("./ux/bck2.png");
                foo = imshow(myimage, cmap, 'Parent', app.UIAxesAct);
                foo.AlphaData = alpha;


                mods = [];
                all_models = dir(['./models/*.mat']);
                mods{1} = '';
                for jk = 2:length(all_models)+1
                    mods{jk} = all_models(jk-1).name(1:end-4);
                end

                if isempty(mods)
                    app.SelectsavedmodelDropDown_2.Items = {'Train your model first.'};
                    app.SelectsavedmodelDropDown_2.Value = 'Train your model first.';
                    app.SelectsavedmodelDropDown_2.Enable = 'off';
                else
                    app.SelectsavedmodelDropDown_2.Enable = 'on';
                    app.SelectsavedmodelDropDown_2.Items = mods;
                    app.SelectsavedmodelDropDown_2.Value = '';
                end
            end
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            disp('ok')
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            disp('ok')

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest augmenter model2use flag
            indices = event.Indices;
            newData = event.NewData;
            augmenterSettings = struct(); % Initialize an empty struct

            for ix = 1:length(app.UITable.Data.Apply)
                if app.UITable.Data.Apply{ix} == 1
                    data_f = app.UITable.Data.Value{ix};
                    data_f = data_f(find(~isspace(data_f)));
                    data_f = strsplit(data_f, ',');
                    if length(data_f) > 1
                        val_data = [str2num(data_f{1}) str2num(data_f{2})];
                    else
                        val_data = str2num(data_f{1});
                    end
                    augmenterSettings(1).(app.UITable.Data.Operation{ix}) = val_data;
                end
            end

            augmenter = imageDataAugmenter(augmenterSettings);
            if contains(flag, 'Classification')
                auimds = augmentedImageDatastore([126 126 3], imdsTrain, 'DataAugmentation', augmenter)
                dataOut = read(auimds);
                dataOut_in = dataOut.input;
                imshow(dataOut_in{1}, 'Parent', app.UIAxes4)
                imshow(dataOut_in{2}, 'Parent', app.UIAxes4_2)
                imshow(dataOut_in{3}, 'Parent', app.UIAxes4_3)
                imshow(dataOut_in{4}, 'Parent', app.UIAxes4_4)
                imshow(dataOut_in{5}, 'Parent', app.UIAxes4_5)
                imshow(dataOut_in{6}, 'Parent', app.UIAxes4_6)
                imshow(dataOut_in{7}, 'Parent', app.UIAxes4_7)
            elseif contains(flag, 'Segmentation')
                global imds
                auimds = augmentedImageDatastore([126 126 3], imds, 'DataAugmentation', augmenter)
                dataOut = read(auimds);
                dataOut_in = dataOut.input;
                imshow(dataOut_in{1}, 'Parent', app.UIAxes4_10)
                imshow(dataOut_in{2}, 'Parent', app.UIAxes4_11)
                imshow(dataOut_in{3}, 'Parent', app.UIAxes4_12)
                imshow(dataOut_in{4}, 'Parent', app.UIAxes4_13)
                imshow(dataOut_in{5}, 'Parent', app.UIAxes4_14)
                imshow(dataOut_in{6}, 'Parent', app.UIAxes4_15)
            end
        end

        % Value changed function: OptimizerDropDown
        function OptimizerDropDownValueChanged(app, event)
            val_opt = app.OptimizerDropDown.Value;
            if contains(val_opt, 'sgdm')
                tabs_hp = readtable('./hyperparams/trainingOpts_sgdm.xlsx');
                app.UITable2.Data = tabs_hp;
            elseif contains(val_opt, 'adam')
                tabs_hp = readtable('./hyperparams/trainingOpts_adam.xlsx');
                app.UITable2.Data = tabs_hp;
            elseif contains(val_opt, 'rmsprop')
                tabs_hp = readtable('./hyperparams/trainingOpts_rmsprop.xlsx');
                app.UITable2.Data = tabs_hp;
            elseif contains(val_opt, 'lbfgs')
                tabs_hp = readtable('./hyperparams/trainingOpts_lbfgs.xlsx');
                app.UITable2.Data = tabs_hp;
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest augmenter model2use options flag
            global allTL allTA
            allTL = [];
            allTA = [];
            layers = evalin('base', app.ModeltotrainDropDown.Value);
            if contains(flag, 'Classification')
                if isa(layers, 'dlnetwork')
                    model2use = layers
                else
                    model2use = dlnetwork(layers);
                end
            end

            % Clear previous plots
            cla(app.UIAxesLoss);
            cla(app.UIAxesAccuracy);

            % Set labels and titles
            xlabel(app.UIAxesLoss, 'Iterations');
            ylabel(app.UIAxesLoss, 'Loss');
            title(app.UIAxesLoss, 'Training Loss');
            hold(app.UIAxesLoss, 'on');

            xlabel(app.UIAxesAccuracy, 'Iterations');
            ylabel(app.UIAxesAccuracy, 'Accuracy');
            title(app.UIAxesAccuracy, 'Training Accuracy');
            hold(app.UIAxesAccuracy, 'on');

            imgSize = model2use.Layers(1).InputSize;

            if contains(flag, 'Classification')
                % Apply transformations to training dataset
                auimdsTrain = augmentedImageDatastore(imgSize, imdsTrain, 'DataAugmentation', augmenter);
                % Apply transformations to validation dataset if needed
                if app.IncludevalidationCheckBox.Value == 1
                    auimdsVal = augmentedImageDatastore(imgSize, imdsVal, 'DataAugmentation', augmenter);
                    % Optional: Ensure that validation data has the same transformation
                    % auimdsVal = transform(auimdsVal, @(x) repmat(x, [1 1 1]));  % Ensure it's [height, width, channels]
                end
            elseif contains(flag, 'Segmentation')
                % TODO!
            end
            trainingOpts = struct();

            for ix = 1:length(app.UITable2.Data.Hyperparameter)

                data_f = app.UITable2.Data.Value{ix};
                if isnan(str2double(data_f))
                    trainingOpts(1).(app.UITable2.Data.Hyperparameter{ix}) = erase(string(data_f),"'");
                else
                    trainingOpts(1).(app.UITable2.Data.Hyperparameter{ix}) = str2double(data_f);
                end

            end

            % Add ValidationData only if the checkbox is checked
            if app.IncludevalidationCheckBox.Value == 1
                trainingOpts(1).('ValidationData') = imdsVal; %auimdsVal;
            end

            trainingOpts(1).('OutputFcn') = @(info) updatePlot(app, info);
            if contains(flag, 'Classification')
                trainingOpts(1).('Metrics') = "accuracy";
            end
            options = trainingOptions(string(app.OptimizerDropDown.Value), trainingOpts);

            global alert
            app.TrainingwaitingtostartLabel.Text = ['Training: in progress, please wait... Epoch: 0 of ' num2str(trainingOpts.MaxEpochs)];
            uialert(app.DeepLearningAppUIFigure, ['Please wait.'], '', 'Icon', 'info','Modal', false);
            % try
                global isNewSession
                global stopTrainingFlag
                stopTrainingFlag = false;
                isNewSession = true;%
                if contains(flag, 'Classification')
                    netnet = trainnet(imdsTrain, model2use, string(app.LossfunctionDropDown.Value), options);
                else
                    netnet = trainNetwork(imdsTrain,model2use,options)
                    % netnet = trainnet(imdsTrain, model2use, string(app.LossfunctionDropDown.Value), options)=
                end
                uialert(app.DeepLearningAppUIFigure, ['Training is done.'], '', 'Icon', 'success','Modal', false);
                app.askForFilenameAndSave(netnet)
                app.TrainingwaitingtostartLabel.Text = ['Training: finished. Epoch: ' num2str(trainingOpts.MaxEpochs) ' of ' num2str(trainingOpts.MaxEpochs)];
                stopTrainingFlag = false;
            % catch ME
            %     % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
            %     uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
            %     app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            % end
            % save('./models/',netnet)

        end

        % Callback function
        function PushToolClicked(app, event)
            global projectName
        end

        % Callback function
        function SelectsavedmodelDropDownValueChanged(app, event)
            global imdsTest XTest YTest
            global acc precision recall f1Score classNames YTest loaded_net flag
            try
                if contains(flag, 'Classification')
                    app.ShowgroundtruthmaskCheckBox.Visible = 'off';
                    app.TransparencySliderSegmOv.Visible = 'off';
                    app.TransparencySlider_5Label_2.Visible = 'off';

                    app.Panel.Visible = 'off';
                    app.TransparencySliderGradCAN.Visible = 'on';
                    % app.TransparencySlider_4Label_2.Visible = 'on';
                    app.Slider_4.Visible = 'on';
                    app.GroundtruthclassLabel.Visible = 'on';
                    app.PredictedclassLabel.Visible = 'on';
                    app.ApplyGradCAMButton.Visible = 'on';
                    app.GradCAMclassDropDown.Visible = 'off';
                    app.GradCAMclassDropDownLabel.Visible = 'off';
                    % imT = readall(imdsTest);
                    index = 1;  % Specify the index of the image you want to read (e.g., 5th image)
                    imageFilePathTs = imdsTest.Files{index};
                    imT = imread(imageFilePathTs);
                    imshow(imT, 'Parent', app.UIAxes4_8)
                    %app.Label.Text = [string(imdsTrain.Labels(1))];
                    app.Slider_4.Value = 1
                    app.Slider_4.Limits = [1 length(imdsTest.Files)]

                    value = app.SelectsavedmodelDropDown.Value;

                    loaded_net = load("./models/"+string(value)+".mat");

                    XTest = readall(imdsTest);
                    TTest = imdsTest.Labels;
                    classNames = categories(TTest);

                    XTest = cat(4,XTest{:});
                    XTest = single(XTest);

                    YTest = predict(loaded_net.trainedNet,XTest);
                    YTest = onehotdecode(YTest,classNames,2)
                    app.GroundtruthclassLabel.Text = "Ground truth class: " + string(TTest(index));
                    app.PredictedclassLabel.Text = "Predicted class: " + string(YTest(index));
                    confMat = confusionmat(YTest, TTest);

                    imagesc(app.UIAxesConfMat, confMat);
                    colormap(app.UIAxesConfMat, abyss);  % Change to 'hot', 'jet', 'gray', etc.
                    confChart.Title = 'Confusion Matrix';
                    confChart.XLabel = 'Predicted Class';
                    confChart.YLabel = 'Actual Class';
                    classNames = unique(imdsTest.Labels);
                    % Set tick labels and add class names
                    % Set the tick marks for the x and y axes
                    xticks(app.UIAxesConfMat, 1:length(classNames));  % Set the x-ticks
                    yticks(app.UIAxesConfMat, 1:length(classNames));  % Set the y-ticks

                    % Add class names as tick labels
                    xticklabels(app.UIAxesConfMat, classNames);
                    yticklabels(app.UIAxesConfMat, classNames);

                    % Change the color of the x-tick labels
                    xtickLabelObjs = findobj(app.UIAxesConfMat, 'Tag', 'XTickLabel');  % Find all x-tick label objects
                    set(xtickLabelObjs, 'Color', 'black');  % Change color to black (or any other color)

                    % Change the color of the y-tick labels
                    ytickLabelObjs = findobj(app.UIAxesConfMat, 'Tag', 'YTickLabel');  % Find all y-tick label objects
                    set(ytickLabelObjs, 'Color', 'black');  % Change color to black (or any other color)
                    colorbar(app.UIAxesConfMat, 'off');  % Hides the colorbar

                    for i = 1:size(confMat, 1)  % Rows
                        for j = 1:size(confMat, 2)  % Columns
                            text(app.UIAxesConfMat, j, i, num2str(confMat(i, j)), ...
                                'Color', 'white', ...   % Text color (change for visibility)
                                'FontSize', 12, ...
                                'FontWeight', 'bold', ...
                                'HorizontalAlignment', 'center');
                        end
                    end

                    app.MetricsDropDown.Items = [categorical("Overall"); classNames]
                    clear acc precision recall f1Score
                    acc{1} = sum(diag(confMat)) / sum(confMat(:));

                    TP = confMat(1, 1);  % True Positives
                    FP = confMat(2, 1);  % False Positives
                    FN = confMat(1, 2);  % False Negatives
                    TN = confMat(2, 2);  % True Negatives

                    precision{1} = TP / (TP + FP);
                    recall{1} = TP / (TP + FN);
                    f1Score{1} = 2 * (precision{1} * recall{1}) / (precision{1} + recall{1});

                    % fprintf('Precision: %.2f\n', precision);
                    % fprintf('Recall: %.2f\n', recall);
                    % fprintf('F1 Score: %.2f\n', f1Score);

                    % For multi-class classification, you can calculate metrics for each class:
                    % numClasses = size(confMat, 1);
                    for ij= 2:length(classNames)+1
                        i = ij-1;
                        TP = confMat(i, i);
                        FP = sum(confMat(:, i)) - TP;
                        FN = sum(confMat(i, :)) - TP;
                        TN = sum(confMat(:)) - TP - FP - FN;

                        precision{ij} = TP / (TP + FP);
                        recall{ij} = TP / (TP + FN);
                        f1Score{ij} = 2 * (precision{ij} * recall{ij}) / (precision{ij} + recall{ij});
                        acc{ij} = (TP+TN)/(TP+TN+FP+FN);
                        % fprintf('Class %d - Precision: %.2f, Recall: %.2f, F1 Score: %.2f\n', i, precision, recall, f1Score);
                    end
                    c_class = app.MetricsDropDown.Value;
                    for i = 1:length(classNames)+1
                        if contains(c_class, app.MetricsDropDown.Items(i))
                            app.AccuracyLabel.Text = sprintf('Accuracy: %.2f%%', acc{i}*100);
                            app.PrecisionLabel.Text = sprintf('Precision: %.2f%%', precision{i}*100);
                            app.RecallLabel.Text = sprintf('Recall: %.2f%%', recall{i}*100);
                            app.F1scoreLabel.Text = sprintf('F1 Score: %.2f%%', f1Score{i}*100);
                        end
                    end
                else
                    app.ShowgroundtruthmaskCheckBox.Visible = 'on';
                    app.TransparencySlider_5Label_2.Visible = 'on';
                    app.TransparencySliderSegmOv.Visible = 'on';
                    app.TransparencySliderSegmOv.Value = 0.5;
                    app.TransparencySliderSegmOv.Limits = [0 1];
                    app.Panel.Visible = 'off';
                    app.TransparencySliderGradCAN.Visible = 'on';
                    % app.TransparencySlider_4Label_2.Visible = 'on';
                    app.Slider_4.Visible = 'on';
                    app.GroundtruthclassLabel.Visible = 'off';
                    app.PredictedclassLabel.Visible = 'off';
                    app.ApplyGradCAMButton.Visible = 'on';
                    app.GradCAMclassDropDown.Visible = 'off';
                    app.GradCAMclassDropDownLabel.Visible = 'off';
                    % imT = readall(imdsTest);
                    index = 1;  % Specify the index of the image you want to read (e.g., 5th image)
                    imageFilePathTs = imdsTest.UnderlyingDatastores{1,1}.Files{index};
                    imT = imread(imageFilePathTs);
                    imshow(imT, 'Parent', app.UIAxes4_8)
                    %app.Label.Text = [string(imdsTrain.Labels(1))];
                    app.Slider_4.Value = 1
                    app.Slider_4.Limits = [1 length(imdsTest.UnderlyingDatastores{1,1}.Files)]

                    value = app.SelectsavedmodelDropDown.Value;

                    loaded_net = load("./models/"+string(value)+".mat");
                    global XTest TTest
                    XTest = readall(imdsTest.UnderlyingDatastores{1,1});
                    TTest = readall(imdsTest.UnderlyingDatastores{1,2});
                    classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;

                    global pxdsResults

                    pxdsResults = semanticseg(imdsTest.UnderlyingDatastores{1,1},loaded_net.trainedNet,Classes=classNames, WriteLocation = 'D:/');
                    metrics = evaluateSemanticSegmentation(pxdsResults,imdsTest.UnderlyingDatastores{1,2});

                    imshow(labeloverlay(imT, imresize(imread(pxdsResults.Files{1}), [size(imT, 1) size(imT, 2)]), 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes2)

                    confMat = table2array(metrics.NormalizedConfusionMatrix);

                    imagesc(app.UIAxesConfMat, confMat);
                    colormap(app.UIAxesConfMat, abyss);  % Change to 'hot', 'jet', 'gray', etc.
                    confChart.Title = 'Confusion Matrix';
                    confChart.XLabel = 'Predicted Class';
                    confChart.YLabel = 'Actual Class';
                    xticks(app.UIAxesConfMat, 1:length(classNames));  % Set the x-ticks
                    yticks(app.UIAxesConfMat, 1:length(classNames));  % Set the y-ticks

                    % Add class names as tick labels
                    xticklabels(app.UIAxesConfMat, classNames);
                    yticklabels(app.UIAxesConfMat, classNames);

                    % Change the color of the x-tick labels
                    xtickLabelObjs = findobj(app.UIAxesConfMat, 'Tag', 'XTickLabel');  % Find all x-tick label objects
                    set(xtickLabelObjs, 'Color', 'black');  % Change color to black (or any other color)

                    % Change the color of the y-tick labels
                    ytickLabelObjs = findobj(app.UIAxesConfMat, 'Tag', 'YTickLabel');  % Find all y-tick label objects
                    set(ytickLabelObjs, 'Color', 'black');  % Change color to black (or any other color)
                    colorbar(app.UIAxesConfMat, 'off');  % Hides the colorbar

                    for i = 1:size(confMat, 1)  % Rows
                        for j = 1:size(confMat, 2)  % Columns
                            text(app.UIAxesConfMat, j, i, num2str(confMat(i, j)), ...
                                'Color', 'white', ...   % Text color (change for visibility)
                                'FontSize', 12, ...
                                'FontWeight', 'bold', ...
                                'HorizontalAlignment', 'center');
                        end
                    end

                    app.MetricsDropDown_2.Items = [categorical("Overall"); classNames]
                    app.MetricsDropDown_2.Value = "Overall";
                    global accGlobal accMean meanIoU WeightedIoU MeanBFScore accClasses IoUClasses MeanBFClasses
                    % clear accGlobal accMean meanIoU WeightedIoU MeanBFScore accClasses IoUClasses MeanBFClasses
                    accGlobal = metrics.DataSetMetrics.GlobalAccuracy;
                    accMean = metrics.DataSetMetrics.MeanAccuracy;
                    meanIoU = metrics.DataSetMetrics.MeanIoU;
                    WeightedIoU = metrics.DataSetMetrics.WeightedIoU;
                    MeanBFScore  = metrics.DataSetMetrics.MeanBFScore;

                    app.GlobalaccuracyLabel.Text = "Global accuracy: " + num2str(round(accGlobal,3));
                    app.MeanaccuracyLabel.Text = "Mean accuracy: " + num2str(round(accMean,3));
                    app.MeanIoULabel.Text = "Mean IoU: " + num2str(round(meanIoU,3));
                    app.WeightedIoULabel.Text = "Weighted IoU: " + num2str(round(WeightedIoU,3));
                    app.MeanBoundaryF1scoreLabel.Text = "Mean boundary F1 score: " + num2str(round(MeanBFScore,3));

                    % fprintf('Precision: %.2f\n', precision);
                    % fprintf('Recall: %.2f\n', recall);
                    % fprintf('F1 Score: %.2f\n', f1Score);

                    % For multi-class classification, you can calculate metrics for each class:
                    % numClasses = size(confMat, 1);
                    for ij= 1:length(classNames)
                        accClasses{ij} = metrics.ClassMetrics{ij,1};
                        IoUClasses{ij} = metrics.ClassMetrics{ij,2};
                        MeanBFClasses{ij} = metrics.ClassMetrics{ij,3};

                    end

                    assignin('base','accClasses',accClasses)
                    assignin('base','IoUClasses',IoUClasses)
                    assignin('base','MeanBFClasses',MeanBFClasses)

                    assignin('base','accGlobal',accGlobal)
                    assignin('base','accMean',accMean)
                    assignin('base','meanIoU',meanIoU)
                    assignin('base','WeightedIoU',WeightedIoU)
                    assignin('base','MeanBFScore',MeanBFScore)
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Button pushed function: ApplyGradCAMButton
        function ApplyGradCAMButtonPushed(app, event)
            try
                global imdsTest loaded_net flag
                value = app.SelectsavedmodelDropDown.Value;
                % loaded_net = load("./models/"+string(value)+".mat");
                app.UIAxes4_9.Visible = 'on';
                app.ApplyGradCAMButton.Visible = 'off';
                valueSlider = app.Slider_4.Value;
                index = round(valueSlider)
                if contains(flag, 'Classification')
                    imageFilePathTs = imdsTest.Files{index};
                    imgsTs = imread(imageFilePathTs);
                    imgsTs = imresize(imgsTs, [app.ImagesizeEditField_x.Value app.ImagesizeEditField_y.Value]);
                    % imgsTs = readall(imdsTest);
                    TTest = imdsTest.Labels;

                    %scoreMap = gradCAM(loaded_net.trainedNet,imgsTs{round(valueSlider)},TTest{round(valueSlider)},ReductionLayer="prob");
                    uialert(app.DeepLearningAppUIFigure, 'Please wait for the results.', '', 'Icon', 'info');

                    scoreMap = gradCAM(loaded_net.trainedNet, imgsTs, TTest(round(valueSlider)));
                    imshow(imgsTs, 'Parent', app.UIAxes4_9);
                    hold(app.UIAxes4_9, 'on');  % Keep the axes active for overlay

                    % Overlay score map with transparency
                    imagesc(app.UIAxes4_9, scoreMap, 'AlphaData', app.TransparencySliderGradCAN.Value);
                    colormap(app.UIAxes4_9, 'jet');  % Apply 'jet' colormap to scoreMap

                    % Adjust properties of the axes as needed
                    axis(app.UIAxes4_9, 'image');  % Ensure the aspect ratio is correct
                else
                    global imdsTest
                    global acc precision recall f1Score classNames YTest loaded_net flag
                    global XTest scoreMap imgsTs
                    imgsTs = XTest{index};
                    imgsTs = imresize(imgsTs, [app.ImagesizeEditField_x.Value app.ImagesizeEditField_y.Value]);
                    % imgsTs = readall(imdsTest);
                    classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;

                    %scoreMap = gradCAM(loaded_net.trainedNet,imgsTs{round(valueSlider)},TTest{round(valueSlider)},ReductionLayer="prob");
                    uialert(app.DeepLearningAppUIFigure, 'Please wait for the results.', '', 'Icon', 'info');
                    if contains(flag, 'Classification')
                        scoreMap = gradCAM(loaded_net.trainedNet, imgsTs, classNames);
                        imshow(imgsTs, 'Parent', app.UIAxes4_9);
                        hold(app.UIAxes4_9, 'on');  % Keep the axes active for overlay

                        % Overlay score map with transparency
                        imagesc(app.UIAxes4_9, scoreMap, 'AlphaData', app.TransparencySliderGradCAN.Value);
                        colormap(app.UIAxes4_9, 'jet');  % Apply 'jet' colormap to scoreMap

                        % Adjust properties of the axes as needed
                        axis(app.UIAxes4_9, 'image');  % Ensure the aspect ratio is correct
                    else
                        app.GradCAMclassDropDownLabel.Visible = 'on';
                        app.GradCAMclassDropDown.Visible = 'on';
                        app.GradCAMclassDropDown.Items = classNames;
                        layNames = loaded_net.trainedNet.Layers;
                        scoreMap = gradCAM(loaded_net.trainedNet, imgsTs, classNames, ...
                            ReductionLayer=layNames(end-1).Name, ...
                            FeatureLayer=layNames(end-2).Name);
                        imshow(imgsTs, 'Parent', app.UIAxes4_9);
                        hold(app.UIAxes4_9, 'on');  % Keep the axes active for overlay

                        imagesc(app.UIAxes4_9, scoreMap(:,:,1), 'AlphaData', app.TransparencySliderGradCAN.Value);
                        colormap(app.UIAxes4_9, 'jet');  % Apply 'jet' colormap to scoreMap

                        axis(app.UIAxes4_9, 'image');  % Ensure the aspect ratio is correct

                    end
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            try
                changingValue = event.Value;
                cla(app.UIAxes4_9);
                % [myimage, cmap, alpha] = imread("./ux/bck2.png");
                % foo3 = imshow(myimage, cmap, 'Parent', app.UIAxes4_9);
                % foo3.AlphaData = alpha;

                app.UIAxes4_9.Visible='off';
                app.ApplyGradCAMButton.Visible = 'on';
                app.GradCAMclassDropDown.Visible = 'off';
                app.GradCAMclassDropDownLabel.Visible = 'off';
                global imdsTest YTest loaded_net flag

                value = app.Slider_4.Value;
                value = app.SelectsavedmodelDropDown.Value;
                % loaded_net = load("./models/"+string(value)+".mat");
                if contains(flag, 'Classification')
                    % valueSlider = app.Slider_4.Value;
                    index = round(changingValue)
                    imageFilePathTs = imdsTest.Files{index};
                    imgsTs = imread(imageFilePathTs);
                    % imgsTs = readall(imdsTest);
                    TTyest = imdsTest.Labels;
                    imshow(imgsTs, 'Parent', app.UIAxes4_8);

                    app.GroundtruthclassLabel.Text = "Ground truth class: " + string(TTyest(index));
                    app.PredictedclassLabel.Text = "Predicted class: " + string(YTest(index));
                else
                    global imdsTest pxdsResults
                    global acc precision recall f1Score classNames YTest loaded_net flag
                    global XTest TTest

                    if app.ShowgroundtruthmaskCheckBox.Value == 1
                        classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;
                        index = round(changingValue);
                        transparencyValue = app.TransparencySliderSegmOv.Value;
                        A = uint8(TTest{index});
                        B = imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]);
                        rgbImage = A+B;
                        rgbImage = rgbImage-1;
                        imshow(labeloverlay(XTest{index}, rgbImage, 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes4_8)
                    else
                        classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;
                        index = round(changingValue);
                        transparencyValue = app.TransparencySliderSegmOv.Value;
                        overlayResults = labeloverlay(XTest{index}, imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]), 'Transparency', transparencyValue);
                        imshow(overlayResults, 'Parent', app.UIAxes4_8);  % Overlay predicted mask
                    end
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            global imdsTest YTest loaded_net flag
            try
                cla(app.UIAxes4_9);
                valueSli = app.Slider_4.Value;
                value = app.SelectsavedmodelDropDown.Value;
                % loaded_net = load("./models/"+string(value)+".mat");
                app.UIAxes4_9.Visible='off';
                app.ApplyGradCAMButton.Visible = 'on';
                app.GradCAMclassDropDown.Visible = 'off';
                app.GradCAMclassDropDownLabel.Visible = 'off';
                index = round(valueSli)
                if contains(flag, 'Classification')

                    imageFilePathTs = imdsTest.Files{index};
                    imgsTs = imread(imageFilePathTs);
                    % imgsTs = readall(imdsTest);
                    TTyest = imdsTest.Labels;
                    imshow(imgsTs, 'Parent', app.UIAxes4_8);
                    app.GroundtruthclassLabel.Text = "Ground truth class: " + string(TTyest(index));
                    app.PredictedclassLabel.Text = "Predicted class: " + string(YTest(index));
                else
                    global imdsTest pxdsResults
                    global acc precision recall f1Score classNames YTest loaded_net flag
                    global XTest TTest

                    if app.ShowgroundtruthmaskCheckBox.Value == 1
                        classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;
                        index = round(valueSli);
                        % imshow(labeloverlay(imT, pxdsResults{1}, 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes2)
                        transparencyValue = app.TransparencySliderSegmOv.Value;
                        imshow(XTest{index}, 'Parent', app.UIAxes4_8);
                        % Overlay ground truth mask (in green)
                        A = uint8(TTest{index});
                        B = imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]);
                        rgbImage = A+B;
                        rgbImage = rgbImage-1;
                        imshow(labeloverlay(XTest{index}, rgbImage, 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes4_8)


                    else
                        classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;
                        index = round(valueSli);
                        imshow(XTest{index}, 'Parent', app.UIAxes4_8);

                        % imshow(labeloverlay(imT, pxdsResults{1}, 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes2)
                        transparencyValue = app.TransparencySliderSegmOv.Value;

                        overlayResults = labeloverlay(XTest{index}, imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]), 'Transparency', transparencyValue);

                        imshow(overlayResults, 'Parent', app.UIAxes4_8);  % Overlay predicted mask
                        % hPrediction   = plot(app.UIAxes4_8, NaN, NaN, 's', 'MarkerFaceColor', [1 0 0], 'MarkerEdgeColor', [1 0 0]);
                        % legend(app.UIAxes4_8, [hPrediction], {'Prediction'}, 'TextColor', 'w');

                        % hold(app.UIAxes4_8, 'off');
                    end
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            if contains(selectedTab.Title, 'Classification')
                cla(app.UIAxesModel)
                app.SelectmodelButtonGroup.SelectedObject.Value = 1;
                layers = imageInputLayer([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)]); % Minimal input layer
                model2use = layers;
                app.BackboneDropDown.Enable = 'off';

                lg = layerGraph(model2use);
                fig = figure;
                plot(lg);
                ax = gca;  % Get the current axes of the figure
                copyobj(allchild(ax), app.UIAxesModel);
                close(fig)
            else
                cla(app.UIAxesModel)
                app.SelectmodelButtonGroup_2.SelectedObject.Value = 1;
                layers = [
                    imageInputLayer([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)])
                    convolution2dLayer([3,3],64,Padding=[1,1,1,1])
                    reluLayer
                    maxPooling2dLayer([2,2],Stride=[2,2])
                    convolution2dLayer([3,3],64,Padding=[1,1,1,1])
                    reluLayer
                    transposedConv2dLayer([4,4],64,Stride=[2,2],Cropping=[1,1,1,1])
                    convolution2dLayer([1,1],3)
                    softmaxLayer];
                model2use = layers;
                app.BackboneDropDown.Enable = 'off';

                lg = layerGraph(model2use);
                fig = figure;
                plot(lg);
                ax = gca;  % Get the current axes of the figure
                copyobj(allchild(ax), app.UIAxesModel);
                close(fig)
            end

        end

        % Selection changed function: SelectmodelButtonGroup_2
        function SelectmodelButtonGroup_2SelectionChanged(app, event)
            global model2use
            try
                selectedButton = app.SelectmodelButtonGroup_2.SelectedObject;
                if contains(selectedButton.Text,'Simple segmentation network')
                    layers = [
                        imageInputLayer([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)])
                        convolution2dLayer([3,3],64,Padding=[1,1,1,1])
                        reluLayer
                        maxPooling2dLayer([2,2],Stride=[2,2])
                        convolution2dLayer([3,3],64,Padding=[1,1,1,1])
                        reluLayer
                        transposedConv2dLayer([4,4],64,Stride=[2,2],Cropping=[1,1,1,1])
                        convolution2dLayer([1,1],3)
                        softmaxLayer];
                    model2use = layerGraph(layers);
                    app.BackboneDropDown.Enable = 'off';

                    lg = layerGraph(model2use);
                    fig = figure;
                    plot(lg);
                    ax = gca;  % Get the current axes of the figure
                    copyobj(allchild(ax), app.UIAxesModel);
                    close(fig)

                    %deepNetworkDesigner(net);
                elseif contains(selectedButton.Text,'DeepLabv3+')
                    app.BackboneDropDown.Enable = 'on';
                    if contains( app.BackboneDropDown.Value, 'ResNet50')
                        bckbone = 'resnet50';
                    end
                    model2use = deeplabv3plusLayers([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)], round(app.NumberofclassesEditField.Value),bckbone);
                    lg = model2use;
                    fig = figure;
                    plot(lg);
                    ax = gca;  % Get the current axes of the figure
                    copyobj(allchild(ax), app.UIAxesModel);
                    close(fig)
                elseif contains(selectedButton.Text,'UNet')
                    model2use = unetLayers([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)], round(app.NumberofclassesEditField.Value));
                    lg = model2use;
                    fig = figure;
                    plot(lg);
                    ax = gca;  % Get the current axes of the figure
                    copyobj(allchild(ax), app.UIAxesModel);
                    close(fig)
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            try
                valueTr = app.TransparencySlider.Value;
                value = app.Slider.Value;
                global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
                if contains(flag, 'Segmentation')
                    global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                    app.TransparencySlider.Visible = 'on';
                    app.TransparencySliderLabel.Visible = 'on';
                    app.TransparencySlider_2.Visible = 'on';
                    app.TransparencySlider_2Label.Visible = 'on';
                    app.TransparencySlider_3.Visible = 'on';
                    app.TransparencySlider_3Label.Visible = 'on';

                    app.UIAxes.Visible = 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.UIAxes_3.Visible = 'off';
                    app.Label.Visible = 'off';
                    app.Label_2.Visible = 'off';
                    app.Label_3.Visible = 'off';
                    app.ClassLabel.Visible = 'off';
                    app.ClassLabel_2.Visible = 'off';
                    app.ClassLabel_3.Visible = 'off';

                    index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                    imageFilePathTr = imdsTrain_im.Files{index};
                    imdsTrain_im_ra = imread(imageFilePathTr);
                    pixFilePathTr = pxdsTrain.Files{index};
                    pxdsTrain_ra = imread(pixFilePathTr);
                    imshow(labeloverlay(imdsTrain_im_ra, pxdsTrain_ra, 'Transparency', valueTr), 'Parent', app.UIAxes2)
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changing function: TransparencySlider
        function TransparencySliderValueChanging(app, event)
            try
                changingValue = event.Value;
                value = app.Slider.Value;
                global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
                if contains(flag, 'Segmentation')
                    global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                    app.TransparencySlider.Visible = 'on';
                    app.TransparencySlider_2.Visible = 'on';
                    app.TransparencySlider_3.Visible = 'on';

                    app.UIAxes.Visible = 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.UIAxes_3.Visible = 'off';
                    app.Label.Visible = 'off';
                    app.Label_2.Visible = 'off';
                    app.Label_3.Visible = 'off';
                    app.ClassLabel.Visible = 'off';
                    app.ClassLabel_2.Visible = 'off';
                    app.ClassLabel_3.Visible = 'off';

                    index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                    imageFilePathTr = imdsTrain_im.Files{index};
                    imdsTrain_im_ra = imread(imageFilePathTr);
                    pixFilePathTr = pxdsTrain.Files{index};
                    pxdsTrain_ra = imread(pixFilePathTr);
                    imshow(labeloverlay(imdsTrain_im_ra, pxdsTrain_ra, 'Transparency', changingValue), 'Parent', app.UIAxes2)
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changed function: TransparencySlider_2
        function TransparencySlider_2ValueChanged(app, event)
            try
                valueTr = app.TransparencySlider_2.Value;
                value = app.Slider_2.Value;
                global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
                if contains(flag, 'Segmentation')
                    global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                    app.TransparencySlider.Visible = 'on';
                    app.TransparencySlider_2.Visible = 'on';
                    app.TransparencySlider_3.Visible = 'on';

                    app.UIAxes.Visible = 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.UIAxes_3.Visible = 'off';
                    app.Label.Visible = 'off';
                    app.Label_2.Visible = 'off';
                    app.Label_3.Visible = 'off';
                    app.ClassLabel.Visible = 'off';
                    app.ClassLabel_2.Visible = 'off';
                    app.ClassLabel_3.Visible = 'off';

                    index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)

                    imageFilePathVal = imdsVal_im.Files{index};
                    imdsVal_im_ra = imread(imageFilePathVal);
                    pixFilePathVal = pxdsVal.Files{index};
                    pxdsVal_ra = imread(pixFilePathVal);

                    imshow(labeloverlay(imdsVal_im_ra, pxdsVal_ra, 'Transparency', valueTr), 'Parent', app.UIAxes2_2)
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changing function: TransparencySlider_2
        function TransparencySlider_2ValueChanging(app, event)
            try
                changingValue = event.Value;
                value = app.Slider_2.Value;
                global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
                if contains(flag, 'Segmentation')
                    global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                    app.TransparencySlider.Visible = 'on';
                    app.TransparencySlider_2.Visible = 'on';
                    app.TransparencySlider_3.Visible = 'on';

                    app.UIAxes.Visible = 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.UIAxes_3.Visible = 'off';
                    app.Label.Visible = 'off';
                    app.Label_2.Visible = 'off';
                    app.Label_3.Visible = 'off';
                    app.ClassLabel.Visible = 'off';
                    app.ClassLabel_2.Visible = 'off';
                    app.ClassLabel_3.Visible = 'off';

                    index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)

                    imageFilePathVal = imdsVal_im.Files{index};
                    imdsVal_im_ra = imread(imageFilePathVal);
                    pixFilePathVal = pxdsVal.Files{index};
                    pxdsVal_ra = imread(pixFilePathVal);

                    imshow(labeloverlay(imdsVal_im_ra, pxdsVal_ra, 'Transparency', changingValue), 'Parent', app.UIAxes2_2)
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changed function: TransparencySlider_3
        function TransparencySlider_3ValueChanged(app, event)
            try
                changingValue = app.TransparencySlider_3.Value;
                value = app.Slider_3.Value;
                global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
                if contains(flag, 'Segmentation')
                    global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                    app.TransparencySlider.Visible = 'on';
                    app.TransparencySlider_2.Visible = 'on';
                    app.TransparencySlider_3.Visible = 'on';

                    app.UIAxes.Visible = 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.UIAxes_3.Visible = 'off';
                    app.Label.Visible = 'off';
                    app.Label_2.Visible = 'off';
                    app.Label_3.Visible = 'off';
                    app.ClassLabel.Visible = 'off';
                    app.ClassLabel_2.Visible = 'off';
                    app.ClassLabel_3.Visible = 'off';

                    index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)

                    imageFilePathTest = imdsTest_im.Files{index};
                    imdsTest_im_ra = imread(imageFilePathTest);
                    pixFilePathTest = pxdsTest.Files{index};
                    pxdsTest_ra = imread(pixFilePathTest);
                    imshow(labeloverlay(imdsTest_im_ra, pxdsTest_ra, 'Transparency', changingValue), 'Parent', app.UIAxes2_3)
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changing function: TransparencySlider_3
        function TransparencySlider_3ValueChanging(app, event)
            try
                changingValue = event.Value;
                value = app.Slider_3.Value;
                global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
                if contains(flag, 'Segmentation')
                    global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                    app.TransparencySlider.Visible = 'on';
                    app.TransparencySlider_2.Visible = 'on';
                    app.TransparencySlider_3.Visible = 'on';

                    app.UIAxes.Visible = 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.UIAxes_3.Visible = 'off';
                    app.Label.Visible = 'off';
                    app.Label_2.Visible = 'off';
                    app.Label_3.Visible = 'off';
                    app.ClassLabel.Visible = 'off';
                    app.ClassLabel_2.Visible = 'off';
                    app.ClassLabel_3.Visible = 'off';

                    index = round(value);
                    imageFilePathTest = imdsTest_im.Files{index};
                    imdsTest_im_ra = imread(imageFilePathTest);
                    pixFilePathTest = pxdsTest.Files{index};
                    pxdsTest_ra = imread(pixFilePathTest);
                    imshow(labeloverlay(imdsTest_im_ra, pxdsTest_ra, 'Transparency', changingValue), 'Parent', app.UIAxes2_3)
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changed function: MetricsDropDown
        function MetricsDropDownValueChanged(app, event)
            global acc precision recall f1Score classNames
            try
                c_class = app.MetricsDropDown.Value;
                for i = 1:length(classNames)+1
                    if contains(c_class, app.MetricsDropDown.Items(i))
                        app.AccuracyLabel.Text = sprintf('Accuracy: %.2f%%', acc{i}*100);
                        app.PrecisionLabel.Text = sprintf('Precision: %.2f%%', precision{i}*100);
                        app.RecallLabel.Text = sprintf('Recall: %.2f%%', recall{i}*100);
                        app.F1scoreLabel.Text = sprintf('F1 Score: %.2f%%', f1Score{i}*100);
                    end
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changed function: TransparencySliderGradCAN
        function TransparencySliderGradCANValueChanged(app, event)
            try
                value = app.TransparencySliderGradCAN.Value;
                global imdsTest loaded_net
                % loaded_net = load("./models/"+string(value)+".mat");

                valueSlider = app.Slider_4.Value;
                index = round(valueSlider)
                imageFilePathTs = imdsTest.Files{index};
                imgsTs = imread(imageFilePathTs);
                imgsTs = imresize(imgsTs, [app.ImagesizeEditField_x.Value app.ImagesizeEditField_y.Value]);
                % imgsTs = readall(imdsTest);
                TTest = imdsTest.Labels;

                scoreMap = gradCAM(loaded_net.trainedNet, imgsTs, TTest(round(valueSlider)));
                imshow(imgsTs, 'Parent', app.UIAxes4_9);
                hold(app.UIAxes4_9, 'on');  % Keep the axes active for overlay

                % Overlay score map with transparency
                imagesc(app.UIAxes4_9, scoreMap, 'AlphaData', app.TransparencySliderGradCAN.Value);
                colormap(app.UIAxes4_9, 'jet');  % Apply 'jet' colormap to scoreMap

                % Adjust properties of the axes as needed
                axis(app.UIAxes4_9, 'image');  % Ensure the aspect ratio is correct
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changing function: TransparencySliderGradCAN
        function TransparencySliderGradCANValueChanging(app, event)
            try
                value = event.Value;
                % value = app.TransparencySliderGradCAN.Value;
                global imdsTest loaded_net
                % loaded_net = load("./models/"+string(value)+".mat");

                valueSlider = app.Slider_4.Value;
                index = round(valueSlider)
                imageFilePathTs = imdsTest.Files{index};
                imgsTs = imread(imageFilePathTs);
                imgsTs = imresize(imgsTs, [app.ImagesizeEditField_x.Value app.ImagesizeEditField_y.Value]);
                % imgsTs = readall(imdsTest);
                TTest = imdsTest.Labels;

                scoreMap = gradCAM(loaded_net.trainedNet, imgsTs, TTest(round(valueSlider)));
                imshow(imgsTs, 'Parent', app.UIAxes4_9);
                hold(app.UIAxes4_9, 'on');  % Keep the axes active for overlay

                % Overlay score map with transparency
                imagesc(app.UIAxes4_9, scoreMap, 'AlphaData', app.TransparencySliderGradCAN.Value);
                colormap(app.UIAxes4_9, 'jet');  % Apply 'jet' colormap to scoreMap

                % Adjust properties of the axes as needed
                axis(app.UIAxes4_9, 'image');  % Ensure the aspect ratio is correct
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Callback function
        function SelectsavedmodelDropDown_2ValueChanged(app, event)
            try
                global loaded_net_act layersNames imgInputImage

                value = app.SelectsavedmodelDropDown_2.Value;
                loaded_net_act = load("./models/"+string(value)+".mat");
                imgInputImage = imresize(imgInputImage, loaded_net_act.trainedNet.Layers(1).InputSize(1:2));
                for i = 1:length(loaded_net_act.trainedNet.Layers)
                    nm = split(class(loaded_net_act.trainedNet.Layers(i)),'.');
                    layersNames{i} = [nm{end} ' [' loaded_net_act.trainedNet.Layers(i).Name ']'];
                end
                app.SelectlayerDropDown.Items = layersNames; %{loaded_net.trainedNet.Layers.Name};
                % actMap = predict(loaded_net_act.trainedNet, double(imgInputImage), 'Outputs', app.SelectlayerDropDown.Value);
                actMap = mat2gray(imgInputImage);
                if ndims(actMap) == 2
                    % Choose a channel (e.g., first feature map)
                    app.FeaturemapnumberSlider.Enable = 'off';
                    app.FeaturemapnumberSliderLabel.Text = 'Feature map: 1/1';
                    featureMap = actMap; % Select one channel
                elseif ndims(actMap) > 2
                    featureMap = actMap(:, :, 1);
                    app.FeaturemapnumberSlider.Enable = 'on';
                    app.FeaturemapnumberSlider.Value = 1;
                    app.FeaturemapnumberSlider.Limits = [1 size(actMap,3)];
                    app.FeaturemapnumberSliderLabel.Text = ['Feature map: 1/' num2str(size(actMap,3))];
                end
                % Display in UIAxes
                imshow(featureMap, [], 'Parent', app.UIAxesAct); % Show in App Designer UIAxes
                title(app.UIAxesAct, ['Feature Map: ', layerName]);
                %colormap(app.UIAxesAct, 'jet'); % Add colormap for clarity
                if app.UsecolormapCheckBox.Value == 1
                    colormap(app.UIAxesAct, 'jet');
                else
                    colormap(app.UIAxesAct, 'gray');
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Callback function
        function SelectlayerDropDownValueChanged(app, event)
            global imdsTest loaded_net_act layersNames actMap layerName imgInputImage flag
            try
                app.FeaturemapnumberSlider.Visible = 'on';
                app.FeaturemapnumberSliderLabel.Visible = 'on';
                if contains(flag, 'Classification')
                    index = randi([1 length(imdsTest.Files)]);
                else
                    index = randi([1 length(imdsTest.UnderlyingDatastores{1,1}.Files)]);
                end
                value = app.SelectlayerDropDown.Value;
                % img  = imread(imdsTest.Files{index});
                img = imresize(imgInputImage, loaded_net_act.trainedNet.Layers(1).InputSize(1:2)); % Resize to network input size

                % Choose a layer (e.g., 'conv1')
                layerName = split(app.SelectlayerDropDown.Value, '['); % 'conv1'; % Change to the desired layer name
                layerName = split(layerName{end}, ']');
                layerName = layerName{1};

                % actMap = activations(loaded_net_act.trainedNet, img, layerName);
                if contains(flag, 'Classification')
                    actMap = predict(loaded_net_act.trainedNet, double(img), 'Outputs', layerName);
                else
                    actMap = activations(loaded_net_act.trainedNet, double(img), layerName);
                    % actMap = deepDreamImage(loaded_net_act.trainedNet, double(img), layerName);
                end
                actMap = imresize(actMap, [800, 800]);
                % Normalize for visualization
                actMap = mat2gray(actMap);
                if ndims(actMap) == 2
                    % Choose a channel (e.g., first feature map)
                    app.FeaturemapnumberSlider.Enable = 'off';
                    app.FeaturemapnumberSliderLabel.Text = 'Feature map: 1/1';
                    featureMap = actMap; % Select one channel
                elseif ndims(actMap) > 2
                    featureMap = actMap(:, :, 1);
                    app.FeaturemapnumberSlider.Enable = 'on';
                    app.FeaturemapnumberSlider.Value = 1;
                    app.FeaturemapnumberSlider.Limits = [1 size(actMap,3)];
                    app.FeaturemapnumberSliderLabel.Text = ['Feature map: 1/' num2str(size(actMap,3))];
                end
                % Display in UIAxes
                imshow(featureMap, [], 'Parent', app.UIAxesAct); % Show in App Designer UIAxes
                title(app.UIAxesAct, ['Feature Map: ', layerName]);
                %colormap(app.UIAxesAct, 'jet'); % Add colormap for clarity
                if app.UsecolormapCheckBox.Value == 1
                    colormap(app.UIAxesAct, 'jet');
                else
                    colormap(app.UIAxesAct, 'gray');
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changed function: FeaturemapnumberSlider
        function FeaturemapnumberSliderValueChanged(app, event)
            global actMap imdsTest layerName
            try
                value = app.FeaturemapnumberSlider.Value;
                if ndims(actMap) == 2
                    % Choose a channel (e.g., first feature map)
                    app.FeaturemapnumberSlider.Enable = 'off';
                    featureMap = actMap; % Select one channel
                elseif ndims(actMap) > 2
                    featureMap = actMap(:, :, round(value)); % Select one channel
                    app.FeaturemapnumberSlider.Enable = 'on';
                    app.FeaturemapnumberSliderLabel.Text = ['Feature map: ' num2str(round(value)) '/' num2str(size(actMap,3))];

                end
                % Display in UIAxes
                imshow(featureMap, [], 'Parent', app.UIAxesAct); % Show in App Designer UIAxes
                title(app.UIAxesAct, ['Feature Map: ', layerName]);
                %colormap(app.UIAxesAct, 'jet'); % Add colormap for clarity
                if app.UsecolormapCheckBox.Value == 1
                    colormap(app.UIAxesAct, 'jet');
                else
                    colormap(app.UIAxesAct, 'gray');
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changing function: FeaturemapnumberSlider
        function FeaturemapnumberSliderValueChanging(app, event)
            try
                value = event.Value;
                global actMap imdsTest layerName
                if ndims(actMap) == 2
                    % Choose a channel (e.g., first feature map)
                    app.FeaturemapnumberSlider.Enable = 'off';
                    featureMap = actMap; % Select one channel
                elseif ndims(actMap) > 2
                    featureMap = actMap(:, :, round(value)); % Select one channel
                    app.FeaturemapnumberSlider.Enable = 'on';
                    app.FeaturemapnumberSliderLabel.Text = ['Feature map: ' num2str(round(value)) '/' num2str(size(actMap,3))];

                end
                % Display in UIAxes
                imshow(featureMap, [], 'Parent', app.UIAxesAct); % Show in App Designer UIAxes
                title(app.UIAxesAct, ['Feature Map: ', layerName]);
                %colormap(app.UIAxesAct, 'jet'); % Add colormap for clarity
                if app.UsecolormapCheckBox.Value == 1
                    colormap(app.UIAxesAct, 'jet');
                else
                    colormap(app.UIAxesAct, 'gray');
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Button pushed function: DrawrandomimageButton
        function DrawrandomimageButtonPushed(app, event)
            global imgInputImage loaded_net_act imdsTest flag
            try
                if contains(flag, 'Classification')
                    index = randi([1 length(imdsTest.Files)]);
                    imgInputImage  = imread(imdsTest.Files{index});
                    %imgInputImage = imresize(img, loaded_net_act.trainedNet.Layers(1).InputSize(1:2)); % Resize to network input size
                    imshow(imgInputImage, [], 'Parent', app.UIAxesII); % Show in App Designer UIAxes
                    title(app.UIAxesII, ['Input image']);
                else
                    index = randi([1 length(imdsTest.UnderlyingDatastores{1, 1}.Files)]);
                    % classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;
                    imgInputImage  = imread(imdsTest.UnderlyingDatastores{1, 1}.Files{index});
                    %imgInputImage = imresize(img, loaded_net_act.trainedNet.Layers(1).InputSize(1:2)); % Resize to network input size
                    imshow(imgInputImage, [], 'Parent', app.UIAxesII); % Show in App Designer UIAxes
                    title(app.UIAxesII, ['Input image']);
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Button pushed function: StoptrainingButton
        function StoptrainingButtonPushed(app, event)
            global stopTrainingFlag;
            stopTrainingFlag = true;  % Set flag to true to stop training
            uialert(app.DeepLearningAppUIFigure, ['Stop button clicked. Stopping training on the next iteration....'], '', 'Icon', 'info','Modal', false);
        end

        % Value changed function: UsecolormapCheckBox
        function UsecolormapCheckBoxValueChanged(app, event)
            try
                global layerName actMap
                value = app.UsecolormapCheckBox.Value;
                actMap = mat2gray(actMap);
                if ndims(actMap) == 2
                    % Choose a channel (e.g., first feature map)
                    app.FeaturemapnumberSlider.Enable = 'off';
                    app.FeaturemapnumberSliderLabel.Text = 'Feature map: 1/1';
                    featureMap = actMap; % Select one channel
                elseif ndims(actMap) > 2
                    featureMap = actMap(:, :, 1);
                    app.FeaturemapnumberSlider.Enable = 'on';
                    app.FeaturemapnumberSlider.Value = 1;
                    app.FeaturemapnumberSlider.Limits = [1 size(actMap,3)];
                    app.FeaturemapnumberSliderLabel.Text = ['Feature map: 1/' num2str(size(actMap,3))];
                end
                % Display in UIAxes
                imshow(featureMap, [], 'Parent', app.UIAxesAct); % Show in App Designer UIAxes
                title(app.UIAxesAct, ['Feature Map: ', layerName]);
                %colormap(app.UIAxesAct, 'jet'); % Add colormap for clarity
                if app.UsecolormapCheckBox.Value == 1
                    colormap(app.UIAxesAct, 'jet');
                else
                    colormap(app.UIAxesAct, 'gray');
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)

            if ishandle(app.DeepLearningAppUIFigure)
                delete(app.DeepLearningAppUIFigure);
                app = DLapp();
            end
        end

        % Value changed function: MetricsDropDown_2
        function MetricsDropDown_2ValueChanged(app, event)
            global accGlobal accMean meanIoU WeightedIoU MeanBFScore accClasses IoUClasses MeanBFClasses classNames
            c_class = app.MetricsDropDown_2.Value;
            try
                if contains(c_class, "Overall")
                    app.GlobalaccuracyLabel.Visible = 'on';
                    app.MeanBoundaryF1scoreLabel.Visible = 'on';
                    app.GlobalaccuracyLabel.Text = "Global accuracy: " + num2str(round(accGlobal,3));
                    app.MeanaccuracyLabel.Text = "Mean accuracy: " + num2str(round(accMean,3));
                    app.MeanIoULabel.Text = "Mean IoU: " + num2str(round(meanIoU,3));
                    app.WeightedIoULabel.Text = "Weighted IoU: " + num2str(round(WeightedIoU,3));
                    app.MeanBoundaryF1scoreLabel.Text = "Mean boundary F1 score: " + num2str(round(MeanBFScore,3));
                else
                    app.GlobalaccuracyLabel.Visible = 'off';
                    app.MeanBoundaryF1scoreLabel.Visible = 'off';
                    for i = 1:length(classNames)
                        if contains(c_class, app.MetricsDropDown_2.Items(i))
                            app.MeanaccuracyLabel.Text = "Accuracy: " + num2str(round(accClasses{i},3));
                            app.MeanIoULabel.Text = "IoU: " + num2str(round(IoUClasses{i},3));
                            app.WeightedIoULabel.Text = "Mean boundary F1 score: " + num2str(round(MeanBFClasses{i},3));
                        end
                    end
                end
            catch ME
                % uialert(app.DeepLearningAppUIFigure, ME.message, 'Error', 'Icon', 'error');
                uialert(app.DeepLearningAppUIFigure, ['Something went wrong... ' ME.message], '', 'Icon', 'error','Modal', false);
                app.TrainingwaitingtostartLabel.Text = 'Training: error, try again'
            end
        end

        % Value changed function: ShowgroundtruthmaskCheckBox
        function ShowgroundtruthmaskCheckBoxValueChanged(app, event)
            global imdsTest pxdsResults
            global acc precision recall f1Score classNames YTest loaded_net flag
            value = app.ShowgroundtruthmaskCheckBox.Value;
            global XTest TTest

            if app.ShowgroundtruthmaskCheckBox.Value == 1
                classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;
                index = round(app.Slider_4.Value);
                imshow(labeloverlay(XTest{index}, imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]), 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes4_8)
                transparencyValue = app.TransparencySliderSegmOv.Value;
                A = uint8(TTest{index});
                B = imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]);
                rgbImage = A+B;
                rgbImage = rgbImage-1;
                imshow(labeloverlay(XTest{index}, rgbImage, 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes4_8)

            else
                classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;
                index = round(app.Slider_4.Value);
                % imshow(labeloverlay(imT, pxdsResults{1}, 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes2)
                transparencyValue = app.TransparencySliderSegmOv.Value;
                overlayResults = labeloverlay(XTest{index}, imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]), 'Transparency', transparencyValue);
                imshow(overlayResults, 'Parent', app.UIAxes4_8);  % Overlay predicted mask
                % hPrediction   = plot(app.UIAxes2, NaN, NaN, 's', 'MarkerFaceColor', [1 0 0], 'MarkerEdgeColor', [1 0 0]);
                % legend(app.UIAxes2, [hPrediction], {'Prediction'}, 'TextColor', 'w');
                %
                % hold(app.UIAxes2, 'off');
            end
        end

        % Value changed function: TransparencySliderSegmOv
        function TransparencySliderSegmOvValueChanged(app, event)
            global imdsTest pxdsResults
            global acc precision recall f1Score classNames YTest loaded_net flag
            global XTest TTest

            if app.ShowgroundtruthmaskCheckBox.Value == 1
                classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;
                index = round(app.Slider_4.Value);
                % imshow(labeloverlay(imT, pxdsResults{1}, 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes4_8)
                transparencyValue = app.TransparencySliderSegmOv.Value;

                A = uint8(TTest{index});
                B = imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]);
                rgbImage = A+B;
                rgbImage = rgbImage-1;
                imshow(labeloverlay(XTest{index}, rgbImage, 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes4_8)


                % hGroundTruth = plot(app.UIAxes2, NaN, NaN, 's', 'MarkerFaceColor', [0 1 0], 'MarkerEdgeColor', [0 1 0]);
                % hPrediction   = plot(app.UIAxes2, NaN, NaN, 's', 'MarkerFaceColor', [1 0 0], 'MarkerEdgeColor', [1 0 0]);
                %
                % Add legend to the axes with your desired labels
                % legend(app.UIAxes2, [hGroundTruth, hPrediction], {'Ground Truth', 'Prediction'}, 'TextColor', 'w');
                %
                % hold(app.UIAxes2, 'off');
            else
                classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;
                index = round(app.Slider_4.Value);
                % imshow(labeloverlay(imT, pxdsResults{1}, 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes4_8)
                transparencyValue = app.TransparencySliderSegmOv.Value;

                overlayResults = labeloverlay(XTest{index}, imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]), 'Transparency', transparencyValue);

                imshow(overlayResults, 'Parent', app.UIAxes4_8);  % Overlay predicted mask
                % hPrediction   = plot(app.UIAxes2, NaN, NaN, 's', 'MarkerFaceColor', [1 0 0], 'MarkerEdgeColor', [1 0 0]);
                % legend(app.UIAxes2, [hPrediction], {'Prediction'}, 'TextColor', 'w');
                %
                % hold(app.UIAxes2, 'off');
            end
        end

        % Value changing function: TransparencySliderSegmOv
        function TransparencySliderSegmOvValueChanging(app, event)
            changingValue = event.Value;
            transparencyValue = changingValue;
            global imdsTest pxdsResults
            global acc precision recall f1Score classNames YTest loaded_net flag
            global XTest TTest

            if app.ShowgroundtruthmaskCheckBox.Value == 1
                classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;
                index = round(app.Slider_4.Value);
                % imshow(labeloverlay(imT, pxdsResults{1}, 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes2)


                % Overlay ground truth mask (in green)
                A = uint8(TTest{index});
                B = imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]);
                rgbImage = A+B;
                rgbImage = rgbImage-1;
                imshow(labeloverlay(XTest{index}, rgbImage, 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes4_8)

                %
                % hGroundTruth = plot(app.UIAxes2, NaN, NaN, 's', 'MarkerFaceColor', [0 1 0], 'MarkerEdgeColor', [0 1 0]);
                % hPrediction   = plot(app.UIAxes2, NaN, NaN, 's', 'MarkerFaceColor', [1 0 0], 'MarkerEdgeColor', [1 0 0]);
                %
                % % Add legend to the axes with your desired labels
                % legend(app.UIAxes2, [hGroundTruth, hPrediction], {'Ground Truth', 'Prediction'}, 'TextColor', 'w');
                %
                % hold(app.UIAxes2, 'off');
            else
                classNames = imdsTest.UnderlyingDatastores{1, 2}.ClassNames;
                index = round(app.Slider_4.Value);
                imshow(labeloverlay(XTest{index}, imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]), 'Transparency', app.TransparencySliderSegmOv.Value), 'Parent', app.UIAxes2)

                overlayResults = labeloverlay(XTest{index}, imresize(imread(pxdsResults.Files{index}), [size(XTest{index}, 1) size(XTest{index}, 2)]), 'Transparency', transparencyValue);

                imshow(overlayResults, 'Parent', app.UIAxes2);  % Overlay predicted mask
                % hPrediction   = plot(app.UIAxes2, NaN, NaN, 's', 'MarkerFaceColor', [1 0 0], 'MarkerEdgeColor', [1 0 0]);
                % legend(app.UIAxes2, [hPrediction], {'Prediction'}, 'TextColor', 'w');
                %
                % hold(app.UIAxes2, 'off');
            end
        end

        % Value changed function: GradCAMclassDropDown
        function GradCAMclassDropDownValueChanged(app, event)
            global imgsTs scoreMap
            value = app.GradCAMclassDropDown.Value;
            imshow(imgsTs, 'Parent', app.UIAxes4_9);
            hold(app.UIAxes4_9, 'on');  % Keep the axes active for overlay

            for i = 1:length(classNames)
                if contains(value, app.GradCAMclassDropDown.Items(i))
                    imagesc(app.UIAxes4_9, scoreMap(:,:,i), 'AlphaData', app.TransparencySliderGradCAN.Value);
                end
            end
            colormap(app.UIAxes4_9, 'jet');  % Apply 'jet' colormap to scoreMap

            axis(app.UIAxes4_9, 'image');  % Ensure the aspect ratio is correct
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DeepLearningAppUIFigure = uifigure('Visible', 'off');
            app.DeepLearningAppUIFigure.Name = 'Deep Learning App ';
            app.Toolbar = uitoolbar(app.DeepLearningAppUIFigure);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Restart app'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'ux', 'clean.png');
            app.PushTool2.Separator = 'on';
            app.TabGroup = uitabgroup(app.DeepLearningAppUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ProblemtosolveTab = uitab(app.TabGroup);
            app.ProblemtosolveTab.Title = 'Problem to solve';
            app.LoaddataButton = uibutton(app.ProblemtosolveTab, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Tooltip = {'Indicate folder with data.'};
            app.LoaddataButton.Text = 'Load data';
            app.MechanismsofartificialintelligencealgorithmsLabel_2 = uilabel(app.ProblemtosolveTab);
            app.MechanismsofartificialintelligencealgorithmsLabel_2.Text = 'Mechanisms of artificial intelligence algorithms';
            app.NetworkTab = uitab(app.TabGroup);
            app.NetworkTab.Title = 'Network';
            app.UIAxesModel = uiaxes(app.NetworkTab);
            app.TabGroup2 = uitabgroup(app.NetworkTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.ClassificationTab = uitab(app.TabGroup2);
            app.ClassificationTab.Title = 'Classification';
            app.SelectmodelButtonGroup = uibuttongroup(app.ClassificationTab);
            app.SelectmodelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectmodelButtonGroupSelectionChanged, true);
            app.SelectmodelButtonGroup.Title = 'Select model';
            app.SimpleclassificationnetworkButton = uitogglebutton(app.SelectmodelButtonGroup);
            app.SimpleclassificationnetworkButton.Text = 'Simple classification network';
            app.SimpleclassificationnetworkButton.Value = true;
            app.AlexNetButton = uitogglebutton(app.SelectmodelButtonGroup);
            app.AlexNetButton.Text = 'AlexNet';
            app.Incepctionv3Button = uitogglebutton(app.SelectmodelButtonGroup);
            app.Incepctionv3Button.Text = 'Incepctionv3';
            app.MobileNetv2Button = uitogglebutton(app.SelectmodelButtonGroup);
            app.MobileNetv2Button.Text = 'MobileNetv2';
            app.ResNet50Button = uitogglebutton(app.SelectmodelButtonGroup);
            app.ResNet50Button.Text = 'ResNet-50';
            app.IncludeweightsCheckBox = uicheckbox(app.SelectmodelButtonGroup);
            app.IncludeweightsCheckBox.Text = 'Include weights';
            app.IncludeweightsCheckBox.Value = true;
            app.ImagesizeEditField_y = uieditfield(app.SelectmodelButtonGroup, 'numeric');
            app.ImagesizeEditField_y.Limits = [2 Inf];
            app.ImagesizeEditField_y.Value = 128;
            app.ImagesizeEditField_z = uieditfield(app.SelectmodelButtonGroup, 'numeric');
            app.ImagesizeEditField_z.Limits = [1 3];
            app.ImagesizeEditField_z.Value = 3;
            app.ImagesizeEditFieldLabel_5 = uilabel(app.SelectmodelButtonGroup);
            app.ImagesizeEditFieldLabel_5.Text = 'x';
            app.ImagesizeEditFieldLabel_6 = uilabel(app.SelectmodelButtonGroup);
            app.ImagesizeEditFieldLabel_6.Text = 'y';
            app.ImagesizeEditFieldLabel_7 = uilabel(app.SelectmodelButtonGroup);
            app.ImagesizeEditFieldLabel_7.Text = 'z';
            app.InputimagesizeLabel = uilabel(app.SelectmodelButtonGroup);
            app.InputimagesizeLabel.Text = 'Input image size';
            app.ImagesizeEditField_x = uieditfield(app.SelectmodelButtonGroup, 'numeric');
            app.ImagesizeEditField_x.Limits = [2 Inf];
            app.ImagesizeEditField_x.Value = 128;
            app.NumberofclassesEditField_2Label = uilabel(app.SelectmodelButtonGroup);
            app.NumberofclassesEditField_2Label.Text = 'Number of classes';
            app.NumberofclassesEditField_2 = uieditfield(app.SelectmodelButtonGroup, 'numeric');
            app.NumberofclassesEditField_2.Limits = [2 Inf];
            app.NumberofclassesEditField_2.RoundFractionalValues = 'on';
            app.NumberofclassesEditField_2.Value = 2;
            app.SegmentationTab = uitab(app.TabGroup2);
            app.SegmentationTab.Title = 'Segmentation';
            app.SelectmodelButtonGroup_2 = uibuttongroup(app.SegmentationTab);
            app.SelectmodelButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SelectmodelButtonGroup_2SelectionChanged, true);
            app.SelectmodelButtonGroup_2.Title = 'Select model';
            app.SimplesegmentationnetworkButton = uitogglebutton(app.SelectmodelButtonGroup_2);
            app.SimplesegmentationnetworkButton.Text = 'Simple segmentation network';
            app.SimplesegmentationnetworkButton.Value = true;
            app.DeepLabv3Button = uitogglebutton(app.SelectmodelButtonGroup_2);
            app.DeepLabv3Button.Text = 'DeepLabv3+';
            app.UNetButton = uitogglebutton(app.SelectmodelButtonGroup_2);
            app.UNetButton.Text = 'UNet';
            app.IncludeweightsCheckBox_2 = uicheckbox(app.SelectmodelButtonGroup_2);
            app.IncludeweightsCheckBox_2.Text = 'Include weights';
            app.IncludeweightsCheckBox_2.Value = true;
            app.NumberofclassesEditFieldLabel = uilabel(app.SelectmodelButtonGroup_2);
            app.NumberofclassesEditFieldLabel.Text = 'Number of classes';
            app.NumberofclassesEditField = uieditfield(app.SelectmodelButtonGroup_2, 'numeric');
            app.NumberofclassesEditField.Limits = [2 Inf];
            app.NumberofclassesEditField.RoundFractionalValues = 'on';
            app.NumberofclassesEditField.Value = 2;
            app.BackboneDropDownLabel = uilabel(app.SelectmodelButtonGroup_2);
            app.BackboneDropDownLabel.Text = 'Backbone';
            app.BackboneDropDown = uidropdown(app.SelectmodelButtonGroup_2);
            app.BackboneDropDown.Items = {'ResNet50'};
            app.BackboneDropDown.Enable = 'off';
            app.BackboneDropDown.Value = 'ResNet50';
            app.ImagesizeEditFieldLabel = uilabel(app.SelectmodelButtonGroup_2);
            app.ImagesizeEditFieldLabel.Text = 'Image size';
            app.ImagesizeEditField_x_s = uieditfield(app.SelectmodelButtonGroup_2, 'numeric');
            app.ImagesizeEditField_x_s.Limits = [2 Inf];
            app.ImagesizeEditField_x_s.RoundFractionalValues = 'on';
            app.ImagesizeEditField_x_s.Value = 224;
            app.ImagesizeEditField_y_s = uieditfield(app.SelectmodelButtonGroup_2, 'numeric');
            app.ImagesizeEditField_y_s.Limits = [2 Inf];
            app.ImagesizeEditField_y_s.RoundFractionalValues = 'on';
            app.ImagesizeEditField_y_s.Value = 224;
            app.ImagesizeEditField_z_s = uieditfield(app.SelectmodelButtonGroup_2, 'numeric');
            app.ImagesizeEditField_z_s.Limits = [1 3];
            app.ImagesizeEditField_z_s.RoundFractionalValues = 'on';
            app.ImagesizeEditField_z_s.Value = 3;
            app.ImagesizeEditFieldLabel_2 = uilabel(app.SelectmodelButtonGroup_2);
            app.ImagesizeEditFieldLabel_2.Text = 'x';
            app.ImagesizeEditFieldLabel_3 = uilabel(app.SelectmodelButtonGroup_2);
            app.ImagesizeEditFieldLabel_3.Text = 'y';
            app.ImagesizeEditFieldLabel_4 = uilabel(app.SelectmodelButtonGroup_2);
            app.ImagesizeEditFieldLabel_4.Text = 'z';
            app.ImagesizeEditFieldLabel_8 = uilabel(app.SelectmodelButtonGroup_2);
            app.ImagesizeEditFieldLabel_8.Text = 'if DeepLabv3+';
            app.ApplyButton = uibutton(app.NetworkTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.TraindatasetEditFieldLabel = uilabel(app.DataTab);
            app.TraindatasetEditFieldLabel.Text = 'Train dataset ';
            app.TraindatasetEditField = uieditfield(app.DataTab, 'numeric');
            app.TraindatasetEditField.Value = 0.8;
            app.ValidationdatasetEditFieldLabel = uilabel(app.DataTab);
            app.ValidationdatasetEditFieldLabel.Text = 'Validation dataset ';
            app.ValidationdatasetEditField = uieditfield(app.DataTab, 'numeric');
            app.ValidationdatasetEditField.Value = 0.1;
            app.IncludevalidationCheckBox = uicheckbox(app.DataTab);
            app.IncludevalidationCheckBox.Text = 'Include validation ';
            app.IncludevalidationCheckBox.Value = true;
            app.TestdatasetLabel = uilabel(app.DataTab);
            app.TestdatasetLabel.Text = 'Test dataset ';
            app.TestdatasetEditField = uieditfield(app.DataTab, 'numeric');
            app.TestdatasetEditField.Value = 0.1;
            app.TabGroup3 = uitabgroup(app.DataTab);
            app.TrainTab_2 = uitab(app.TabGroup3);
            app.TrainTab_2.Title = 'Train';
            app.UIAxes = uiaxes(app.TrainTab_2);
            title(app.UIAxes, 'Labels distribution')
            xlabel(app.UIAxes, 'Class')
            ylabel(app.UIAxes, 'Count')
            app.UIAxes2 = uiaxes(app.TrainTab_2);
            app.Slider = uislider(app.TrainTab_2);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Label = uilabel(app.TrainTab_2);
            app.Label.Text = '-----';
            app.ClassLabel = uilabel(app.TrainTab_2);
            app.ClassLabel.Text = 'Class:';
            app.TransparencySliderLabel = uilabel(app.TrainTab_2);
            app.TransparencySliderLabel.Text = 'Transparency';
            app.TransparencySlider = uislider(app.TrainTab_2);
            app.TransparencySlider.Limits = [0 1];
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.TransparencySlider.ValueChangingFcn = createCallbackFcn(app, @TransparencySliderValueChanging, true);
            app.TransparencySlider.Value = 0.5;
            app.ValidationTab = uitab(app.TabGroup3);
            app.ValidationTab.Title = 'Validation';
            app.UIAxes_2 = uiaxes(app.ValidationTab);
            title(app.UIAxes_2, 'Labels distribution')
            xlabel(app.UIAxes_2, 'Class')
            ylabel(app.UIAxes_2, 'Count')
            app.UIAxes2_2 = uiaxes(app.ValidationTab);
            app.Slider_2 = uislider(app.ValidationTab);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Label_2 = uilabel(app.ValidationTab);
            app.Label_2.Text = '-----';
            app.ClassLabel_2 = uilabel(app.ValidationTab);
            app.ClassLabel_2.Text = 'Class:';
            app.TransparencySlider_2Label = uilabel(app.ValidationTab);
            app.TransparencySlider_2Label.Text = 'Transparency';
            app.TransparencySlider_2 = uislider(app.ValidationTab);
            app.TransparencySlider_2.Limits = [0 1];
            app.TransparencySlider_2.ValueChangedFcn = createCallbackFcn(app, @TransparencySlider_2ValueChanged, true);
            app.TransparencySlider_2.ValueChangingFcn = createCallbackFcn(app, @TransparencySlider_2ValueChanging, true);
            app.TransparencySlider_2.Value = 0.5;
            app.TestTab_2 = uitab(app.TabGroup3);
            app.TestTab_2.Title = 'Test';
            app.UIAxes_3 = uiaxes(app.TestTab_2);
            title(app.UIAxes_3, 'Labels distribution')
            xlabel(app.UIAxes_3, 'Class')
            ylabel(app.UIAxes_3, 'Count')
            app.UIAxes2_3 = uiaxes(app.TestTab_2);
            app.Slider_3 = uislider(app.TestTab_2);
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Label_3 = uilabel(app.TestTab_2);
            app.Label_3.Text = '-----';
            app.ClassLabel_3 = uilabel(app.TestTab_2);
            app.ClassLabel_3.Text = 'Class:';
            app.TransparencySlider_3Label = uilabel(app.TestTab_2);
            app.TransparencySlider_3Label.Text = 'Transparency';
            app.TransparencySlider_3 = uislider(app.TestTab_2);
            app.TransparencySlider_3.Limits = [0 1];
            app.TransparencySlider_3.ValueChangedFcn = createCallbackFcn(app, @TransparencySlider_3ValueChanged, true);
            app.TransparencySlider_3.ValueChangingFcn = createCallbackFcn(app, @TransparencySlider_3ValueChanging, true);
            app.TransparencySlider_3.Value = 0.5;
            app.DatasetsstatisticsLabel = uilabel(app.DataTab);
            app.DatasetsstatisticsLabel.Text = 'Datasets statistics ';
            app.TrainValidationTestdatarandompartitioningLabel = uilabel(app.DataTab);
            app.TrainValidationTestdatarandompartitioningLabel.Text = 'Train - Validation - Test data random partitioning';
            app.ApplypartitioningButton = uibutton(app.DataTab, 'push');
            app.ApplypartitioningButton.ButtonPushedFcn = createCallbackFcn(app, @ApplypartitioningButtonPushed, true);
            app.ApplypartitioningButton.Text = 'Apply partitioning';
            app.DataaugmentationTab = uitab(app.TabGroup);
            app.DataaugmentationTab.Title = 'Data augmentation';
            app.DataaugmentationTab.ButtonDownFcn = createCallbackFcn(app, @DataaugmentationTabButtonDown, true);
            app.PanelClassification = uipanel(app.DataaugmentationTab);
            app.UIAxes4 = uiaxes(app.PanelClassification);
            app.UIAxes4_2 = uiaxes(app.PanelClassification);
            app.UIAxes4_5 = uiaxes(app.PanelClassification);
            app.UIAxes4_4 = uiaxes(app.PanelClassification);
            app.UIAxes4_3 = uiaxes(app.PanelClassification);
            app.UIAxes4_6 = uiaxes(app.PanelClassification);
            app.UIAxes4_7 = uiaxes(app.PanelClassification);
            app.UITable = uitable(app.DataaugmentationTab);
            app.UITable.ColumnName = {'Operation'; 'Value'; 'Description'; 'Apply'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false false];
            app.UITable.ColumnEditable = [false true false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.PreviewaugmentationresultsLabel = uilabel(app.DataaugmentationTab);
            app.PreviewaugmentationresultsLabel.Text = 'Preview augmentation results';
            app.PanelSegmentation = uipanel(app.DataaugmentationTab);
            app.UIAxes4_10 = uiaxes(app.PanelSegmentation);
            app.UIAxes4_11 = uiaxes(app.PanelSegmentation);
            app.UIAxes4_12 = uiaxes(app.PanelSegmentation);
            app.UIAxes4_13 = uiaxes(app.PanelSegmentation);
            app.UIAxes4_14 = uiaxes(app.PanelSegmentation);
            app.UIAxes4_15 = uiaxes(app.PanelSegmentation);
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.UIAxesAccuracy = uiaxes(app.TrainTab);
            title(app.UIAxesAccuracy, 'Title')
            xlabel(app.UIAxesAccuracy, 'X')
            ylabel(app.UIAxesAccuracy, 'Y')
            zlabel(app.UIAxesAccuracy, 'Z')
            app.UIAxesLoss = uiaxes(app.TrainTab);
            title(app.UIAxesLoss, 'Title')
            xlabel(app.UIAxesLoss, 'X')
            ylabel(app.UIAxesLoss, 'Y')
            zlabel(app.UIAxesLoss, 'Z')
            app.HyperparametersPanel = uipanel(app.TrainTab);
            app.HyperparametersPanel.Title = 'Hyperparameters';
            app.OptimizerDropDownLabel = uilabel(app.HyperparametersPanel);
            app.OptimizerDropDownLabel.Text = 'Optimizer';
            app.OptimizerDropDown = uidropdown(app.HyperparametersPanel);
            app.OptimizerDropDown.Items = {'sgdm', 'rmsprop', 'adam ', 'lbfgs'};
            app.OptimizerDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizerDropDownValueChanged, true);
            app.OptimizerDropDown.Value = 'sgdm';
            app.UITable2 = uitable(app.HyperparametersPanel);
            app.UITable2.ColumnName = {'Hyperparameter'; 'Value'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true];
            app.TrainButton = uibutton(app.HyperparametersPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.LossfunctionDropDownLabel = uilabel(app.HyperparametersPanel);
            app.LossfunctionDropDownLabel.Text = 'Loss function';
            app.LossfunctionDropDown = uidropdown(app.HyperparametersPanel);
            app.LossfunctionDropDown.Items = {'crossentropy', 'index-crossentropy', 'binary-crossentropy', 'mean-absolute-error', 'mean-squared-error', 'huber'};
            app.LossfunctionDropDown.Value = 'crossentropy';
            app.ModeltotrainDropDownLabel = uilabel(app.HyperparametersPanel);
            app.ModeltotrainDropDownLabel.Text = 'Model to train';
            app.ModeltotrainDropDown = uidropdown(app.HyperparametersPanel);
            app.ModeltotrainDropDown.Items = {};
            app.ModeltotrainDropDown.Value = {};
            app.TrainingwaitingtostartLabel = uilabel(app.TrainTab);
            app.TrainingwaitingtostartLabel.Text = 'Training: waiting to start';
            app.TimeelapsedLabel = uilabel(app.TrainTab);
            app.TimeelapsedLabel.Text = 'Time elapsed: ----';
            app.StoptrainingButton = uibutton(app.TrainTab, 'push');
            app.StoptrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StoptrainingButtonPushed, true);
            app.StoptrainingButton.Text = 'Stop training';
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.UIAxesConfMat = uiaxes(app.TestTab);
            app.UIAxes4_8 = uiaxes(app.TestTab);
            app.UIAxes4_9 = uiaxes(app.TestTab);
            app.TestmetricsPanel = uipanel(app.TestTab);
            app.TestmetricsPanel.Title = 'Test metrics';
            app.AccuracyLabel = uilabel(app.TestmetricsPanel);
            app.AccuracyLabel.Text = 'Accuracy: ';
            app.PrecisionLabel = uilabel(app.TestmetricsPanel);
            app.PrecisionLabel.Text = 'Precision: ';
            app.RecallLabel = uilabel(app.TestmetricsPanel);
            app.RecallLabel.Text = 'Recall: ';
            app.F1scoreLabel = uilabel(app.TestmetricsPanel);
            app.F1scoreLabel.Text = 'F1 score: ';
            app.MetricsDropDownLabel = uilabel(app.TestmetricsPanel);
            app.MetricsDropDownLabel.Text = 'Metrics';
            app.MetricsDropDown = uidropdown(app.TestmetricsPanel);
            app.MetricsDropDown.Items = {'Overall'};
            app.MetricsDropDown.ValueChangedFcn = createCallbackFcn(app, @MetricsDropDownValueChanged, true);
            app.MetricsDropDown.Value = 'Overall';
            app.SelectsavedmodelDropDownLabel = uilabel(app.TestTab);
            app.SelectsavedmodelDropDownLabel.Text = 'Select saved model';
            app.GroundtruthclassLabel = uilabel(app.TestTab);
            app.GroundtruthclassLabel.Text = 'Ground truth class: ';
            app.PredictedclassLabel = uilabel(app.TestTab);
            app.PredictedclassLabel.Text = 'Predicted class: ';
            app.ApplyGradCAMButton = uibutton(app.TestTab, 'push');
            app.ApplyGradCAMButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyGradCAMButtonPushed, true);
            app.ApplyGradCAMButton.Text = 'Apply GradCAM';
            app.Slider_4 = uislider(app.TestTab);
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.TransparencySlider_5Label = uilabel(app.TestTab);
            app.TransparencySlider_5Label.Text = 'Transparency';
            app.TransparencySliderGradCAN = uislider(app.TestTab);
            app.TransparencySliderGradCAN.Limits = [0 1];
            app.TransparencySliderGradCAN.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderGradCANValueChanged, true);
            app.TransparencySliderGradCAN.ValueChangingFcn = createCallbackFcn(app, @TransparencySliderGradCANValueChanging, true);
            app.TransparencySliderGradCAN.Value = 0.5;
            app.ConfusionmatrixLabel = uilabel(app.TestTab);
            app.ConfusionmatrixLabel.Text = 'Confusion matrix';
            app.SegmentationqualitymetricsPanel = uipanel(app.TestTab);
            app.SegmentationqualitymetricsPanel.Title = 'Segmentation quality metrics';
            app.MetricsDropDown_2Label = uilabel(app.SegmentationqualitymetricsPanel);
            app.MetricsDropDown_2Label.Text = 'Metrics';
            app.MetricsDropDown_2 = uidropdown(app.SegmentationqualitymetricsPanel);
            app.MetricsDropDown_2.Items = {'Overall'};
            app.MetricsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MetricsDropDown_2ValueChanged, true);
            app.MetricsDropDown_2.Value = 'Overall';
            app.GlobalaccuracyLabel = uilabel(app.SegmentationqualitymetricsPanel);
            app.GlobalaccuracyLabel.Text = 'Global accuracy: ---';
            app.MeanaccuracyLabel = uilabel(app.SegmentationqualitymetricsPanel);
            app.MeanaccuracyLabel.Text = 'Mean accuracy: ---';
            app.MeanIoULabel = uilabel(app.SegmentationqualitymetricsPanel);
            app.MeanIoULabel.Text = 'Mean IoU: ---';
            app.WeightedIoULabel = uilabel(app.SegmentationqualitymetricsPanel);
            app.WeightedIoULabel.Text = 'Weighted IoU: ---';
            app.MeanBoundaryF1scoreLabel = uilabel(app.SegmentationqualitymetricsPanel);
            app.MeanBoundaryF1scoreLabel.Text = 'Mean Boundary F1 score: ---';
            app.TransparencySlider_5Label_2 = uilabel(app.TestTab);
            app.TransparencySlider_5Label_2.Text = 'Transparency';
            app.TransparencySliderSegmOv = uislider(app.TestTab);
            app.TransparencySliderSegmOv.Limits = [0 1];
            app.TransparencySliderSegmOv.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderSegmOvValueChanged, true);
            app.TransparencySliderSegmOv.ValueChangingFcn = createCallbackFcn(app, @TransparencySliderSegmOvValueChanging, true);
            app.TransparencySliderSegmOv.Value = 0.5;
            app.ShowgroundtruthmaskCheckBox = uicheckbox(app.TestTab);
            app.ShowgroundtruthmaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowgroundtruthmaskCheckBoxValueChanged, true);
            app.ShowgroundtruthmaskCheckBox.Text = 'Show ground-truth mask';
            app.GradCAMclassDropDownLabel = uilabel(app.TestTab);
            app.GradCAMclassDropDownLabel.Text = 'GradCAM: class';
            app.GradCAMclassDropDown = uidropdown(app.TestTab);
            app.GradCAMclassDropDown.ValueChangedFcn = createCallbackFcn(app, @GradCAMclassDropDownValueChanged, true);
            app.Panel = uipanel(app.TestTab);
            app.NetworkinsidesTab = uitab(app.TabGroup);
            app.NetworkinsidesTab.Title = 'Network insides';
            app.UIAxesAct = uiaxes(app.NetworkinsidesTab);
            app.UIAxesII = uiaxes(app.NetworkinsidesTab);
            app.SelectsavedmodelDropDown_2Label = uilabel(app.NetworkinsidesTab);
            app.SelectsavedmodelDropDown_2Label.Text = 'Select saved model';
            app.SelectlayerDropDownLabel = uilabel(app.NetworkinsidesTab);
            app.SelectlayerDropDownLabel.Text = 'Select layer';
            app.UsecolormapCheckBox = uicheckbox(app.NetworkinsidesTab);
            app.UsecolormapCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsecolormapCheckBoxValueChanged, true);
            app.UsecolormapCheckBox.Text = 'Use colormap';
            app.FeaturemapnumberSliderLabel = uilabel(app.NetworkinsidesTab);
            app.FeaturemapnumberSliderLabel.Text = 'Feature map number';
            app.FeaturemapnumberSlider = uislider(app.NetworkinsidesTab);
            app.FeaturemapnumberSlider.Limits = [1 100];
            app.FeaturemapnumberSlider.ValueChangedFcn = createCallbackFcn(app, @FeaturemapnumberSliderValueChanged, true);
            app.FeaturemapnumberSlider.ValueChangingFcn = createCallbackFcn(app, @FeaturemapnumberSliderValueChanging, true);
            app.FeaturemapnumberSlider.MinorTicks = [];
            app.FeaturemapnumberSlider.Value = 1;
            app.DrawrandomimageButton = uibutton(app.NetworkinsidesTab, 'push');
            app.DrawrandomimageButton.ButtonPushedFcn = createCallbackFcn(app, @DrawrandomimageButtonPushed, true);
            app.DrawrandomimageButton.Text = 'Draw random image';
            % Show the figure after all components are created
            app.DeepLearningAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainDLapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeepLearningAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeepLearningAppUIFigure)
        end
    end
end